classdef MeMRI_Processing_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MeMRIProcessingToolboxUIFigure  matlab.ui.Figure
        mb_Main                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        ConverterMenu                   matlab.ui.container.Menu
        ImportMenu_3                    matlab.ui.container.Menu
        NoiseDataMenu                   matlab.ui.container.Menu
        MRImagesMenu_2                  matlab.ui.container.Menu
        ProtocolParametersMenu          matlab.ui.container.Menu
        MethodsMenu                     matlab.ui.container.Menu
        ImportMenu                      matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        mb_Parameters                   matlab.ui.container.Menu
        PresetMenu                      matlab.ui.container.Menu
        ImportMenu_2                    matlab.ui.container.Menu
        ExportMenu_2                    matlab.ui.container.Menu
        mb_ViewerMenu                   matlab.ui.container.Menu
        CSIguiMenu                      matlab.ui.container.Menu
        ViewerMenu_2                    matlab.ui.container.Menu
        mb_Help                         matlab.ui.container.Menu
        QuickGuideMenu                  matlab.ui.container.Menu
        TutorialMenu                    matlab.ui.container.Menu
        UpdatesMenu                     matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        Button_File_Reload              matlab.ui.control.Button
        Edit_File                       matlab.ui.control.EditField
        BusyLampLabel                   matlab.ui.control.Label
        TabGroup_Settings               matlab.ui.container.TabGroup
        ProcessingTab                   matlab.ui.container.Tab
        tree_methods_kspace_4           matlab.ui.container.CheckBoxTree
        OverwriteNode                   matlab.ui.container.TreeNode
        ExportPresetNode                matlab.ui.container.TreeNode
        WhenfinishedNode                matlab.ui.container.TreeNode
        ShutdownNode                    matlab.ui.container.TreeNode
        OpeninCSIguiNode                matlab.ui.container.TreeNode
        OpendirectoryNode               matlab.ui.container.TreeNode
        Button_File                     matlab.ui.control.Button
        Button_ListBox_Methods_Apply_Down  matlab.ui.control.Button
        Button_ListBox_Methods_Apply_Up  matlab.ui.control.Button
        Button_View_Data                matlab.ui.control.Button
        Button_Method_Remove            matlab.ui.control.Button
        Button_Method_Add               matlab.ui.control.Button
        Button_View_Parameters          matlab.ui.control.Button
        Button_MethodsPreset_Default    matlab.ui.control.Button
        Button_MethodsPreset_Import     matlab.ui.control.Button
        Button_MethodsPreset_Export     matlab.ui.control.Button
        Button_ParametersPreset_Default  matlab.ui.control.Button
        Button_ParametersPreset_Import  matlab.ui.control.Button
        Button_ParametersPreset_Export  matlab.ui.control.Button
        Button_Processing               matlab.ui.control.Button
        Label_Operations                matlab.ui.control.Label
        Label_Methods                   matlab.ui.control.Label
        Label_ParametersPreset          matlab.ui.control.Label
        Label_Details                   matlab.ui.control.Label
        Label_MethodsPreset             matlab.ui.control.Label
        ListBox_Log                     matlab.ui.control.ListBox
        ListBox_Methods_Apply           matlab.ui.control.ListBox
        ListBox_Methods_List            matlab.ui.control.ListBox
        BusyLamp                        matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        % Main memri data struct with all input and parameters.
        filepath = [];
    end
    
    properties (Access = public)
        memri = struct;         % Input data struct
        tabdata = struct;       % Tab-data for TabGroup_Settings
        gui_methods = struct;

        % Colors used in GUI
        clr = struct('bg', [0.15 0.15 0.15], ...
                     'fg', [0.94 0.94 0.94], ...
                     'hl', [0.43 0.17 0.31]);
    end

    % Callbacks
    methods (Access = private)
        
        % \\ Default callbacks for MeMRI GUI -------------------- %
        % ------------------------------------------------------- %

        function memrigui_reset(app)
            % Clear log listbox 
            app.ListBox_Log.Items = ...
                {'Metabolic MRI Processing Toolbox GUI',''};

            % Clear data-tab
            if isfield(app.tabdata, 'Data')
                delete(app.tabdata.Data.tab);
                app.tabdata = rmfield(app.tabdata, 'Data');
            end

            % Reset main filepath
            app.filepath = [];    

            % Reset memri-data struct in app
            app.memri = struct;
        end

        function memrigui_lamp_toggle(app, state)
            if state
            % Enable busy lamp
            app.BusyLamp.Color = 'g'; app.BusyLamp.Enable = 1;
            else
            % Disable busy lamp
            app.BusyLamp.Color = 'w'; app.BusyLamp.Enable = 0;
            end
            pause(0.0075); 
        end


        function methods_initialise(app)
            % Read config file with all available methods, process the 
            % method options and enable in GUI.

            % Get all methods
            app.gui_methods = readMethods();
            if ~isstruct(app.gui_methods) && isnan(app.gui_methods)
                memrigui_log(app,...
                    'Error loading methods module, please check github.',...
                    'See error log for details.');
                return;
            end

            % Set available methods
            flds = fieldnames(app.gui_methods);
            mnames = cellfun(@(x) app.gui_methods.(x).name, flds, ...
                'UniformOutput', false);

            % Add available method modules to methods-list:
            app.ListBox_Methods_List.Items = ...
                cat(1, mnames(:), app.ListBox_Methods_List.Items(:));

        end

        % \\ Tabgroup Settings Callbacks ------------------------ %
        % ------------------------------------------------------- %

        function app = TabGroup_Settings_Initialise(app)
            % Check the "Operations" list and add corresponding tabs
            % to the tab group that allow changing parameters and settings.                      

            % Always check for the data-tab
            if ~isfield(app.tabdata, 'Data')
                app = TabGroup_Settings_Data(app);
            end
 
            % Execute tab-initialisation for each method
            methods2add = app.ListBox_Methods_Apply.Items;
            emptyBool = sum(cellfun(@(x) isempty(x), methods2add));
            if ~emptyBool
                for kk = 1:numel(methods2add)
                    if ~isfield(app.tabdata, methods2add{kk})    
                        % Create tabdata for every method
                        tab_initialise(app, methods2add{kk});           
                    end
                end
            end

            % Immidiate update to flush cosmetics.
            TabGroup_Settings_update(app);

        end
        
        function TabGroup_Settings_update(app)
            % Update order of tabs

            % Methods list
            methods2apply = app.ListBox_Methods_Apply.Items;

            % Tab list
            tnames = {app.TabGroup_Settings.Children(:).Title};

            % Find non-method tabs: Processing and Data
            ind_data = find(strcmp(tnames, 'Data'));
            ind_proc = find(strcmp(tnames, 'Processing'));

            % Sorting vector
            svec = cellfun(@(x) find(strcmp(x, tnames)), methods2apply,...
                'UniformOutput',false); svec = cell2mat(svec);
            svec = [ind_proc ind_data svec];

            % Sort
            if ~isequal(svec, 1:numel(svec))
                app.TabGroup_Settings.Children = ...
                    app.TabGroup_Settings.Children(svec);
            end

        end

        function app = TabGroup_Settings_Data(app)
        % Create the data-tab and display to user - this is custom and
        % different from the methods-tabs.
            
            % Only if data available.
            if ~isempty(app.memri) && isfield(app.memri, 'data')              
                
                % Data NFO details.
                flds = fieldnames(app.memri.nfo);
                vals = struct2cell(app.memri.nfo);

                % < Custom-for-data >
                % Fieldname correction via a matching library:
                lib = {...
                    'nucleus',           'Nucleus: '; ...
                    'fieldstrength',     'Fieldstrength (T):';...
                    'res',               'Resolution [X x Y x Z] (mm):';...
                    'TE',                'Echo-time, TE (s): ';...
                    'TR',                'Repetition time, TR (s): ';...
                    'fov',               'Field-of-View [X x Y x Z] (mm):';...                    
                    'bandwidth',         'Bandwidth (Hz): ';...
                    'offcenter',         'Volume Offcenter [X x Y x Z] (mm):';...
                    'orientation',       'Orientation:';...
                    'dwelltime',         'Dwelltime (s):';...
                    'transmit_frequency','Transmit frequency (Hz):';...
                    'dim',               'Matrix size: ';...
                    'domain',            'Data domain (0 = k-space, 1 = time, 2 = frequency):'...
                    };
                
                % Query input ui-elements
                query_style = repmat({'edit'}, 1, numel(flds));

                % Apply label-text library for data
                for kk = 1:numel(flds)
                    ind = strcmp(lib(:,1), flds{kk});
                    if sum(ind) > 0, flds(kk) = lib(ind,2); end
                end
                % </ Custom-for-data />
                
                % Tab-name
                tname = genVarName('Data');

                % Create tab for data-details
                app.tabdata.(tname).tab = uitab(app.TabGroup_Settings,...
                    'Title', 'Data', ...
                    'ForegroundColor', app.clr.bg ,...
                    'BackgroundColor', app.clr.bg ); % = color of tab-title!
                                
                % Default tab-initialisation of user interface
                tab_initialise_ui(app, tname, query_style);
                             
                % Update UI query-elements in tab-object
                for kk = 1:numel(flds)
                    % Add label text
                    app.tabdata.(tname).label(kk).Text = [flds{kk}];

                    % Convert non-char to string
                    vv = vals{kk};
                    if ~ischar(vals{kk}), vv = values2str(vals{kk}); end

                    % Set inputobject value
                    app.tabdata.(tname).inputobj(kk).Value = vv;                    
                end

                % < Custom for data-tab >
                % Add matrix size and labels to nfo/title label.
                app.tabdata.(tname).tl.FontName = 'Consolas';
                app.tabdata.(tname).tl.HorizontalAlignment = 'center';
                app.tabdata.(tname).tl.VerticalAlignment = 'top';

                % Create text
                linp = sprintf('%8s', app.memri.labels{:});
                minp = sprintf('%8i', size(app.memri.data));                
                txt = sprintf('%s\n%s', linp(5:end), minp(5:end));
                
                % Apply text
                app.tabdata.(tname).tl.Text = txt;
                app.tabdata.(tname).tl.FontWeight = 'bold';
                % </ Custom for data-tab />

            end
        end
        
        function tab_initialise(app, method_name)
        % Create a new tab in the Tabgroup_Settings for every method found
        % in the listbox_methods_apply.
        %
        % Settings need to be loaded here too.

            % // Generate the method its tab name //
            tname = genVarName(method_name);

            % Security check - check if tab is already a child.
            tabnames = cellfun(@genVarName,...
                    {app.TabGroup_Settings.Children(:).Title},...
                    'UniformOutput', false);
            if isfield(app.tabdata, tname) || ismember(tname,tabnames)
                return; 
            end

            % Create tab for data-details
            app.tabdata.(tname).tab = uitab(app.TabGroup_Settings,...
                'Title', method_name, ...
                'ForegroundColor', app.clr.bg ,...
                'BackgroundColor', app.clr.bg ); % = color of tab-title!
            
            % Get options for method to use in tab-query input objects
            if isfield(app.gui_methods, tname) 
                
                opts = struct;
                if isfield(app.gui_methods.(tname), 'opts')
                    % get options for this method
                    opts = app.gui_methods.(tname).opts; 
                end

                % Default tab-initialisation of UI and return:
                %       app.tabdata.(tname).tab, .label, .inputobj, .tl
                tab_initialise_ui(app, tname, opts);   

                % Add all options to the tab-input and lable objects.
                tab_initialise_add_opts(app, tname, opts);

                % Add nfo from opts
                tab_initialise_add_nfo(app, tname);
            end

        end

        function tab_initialise_add_nfo(app, tname)

            % Add NFO to title label in tab if available
            if isfield(app.gui_methods.(tname), 'nfo')
                % Text
                app.tabdata.(tname).tl.Text = app.gui_methods.(tname).nfo;
            end
            
        end

        function tab_initialise_add_opts(app, tname, opts)
            % Add the settings to the each label input object in the tab
           
            % Only if queries available.
            if ~isfield(opts, 'query'), return; end

            % See comments for opts-details.
            qry    = {opts(:).query};   % Label query
            dval   = {opts(:).display}; % Displayed values
            uitype = {opts(:).uistyle}; % input object style

            % Process display values
            dval = cellfun(@(x) strtrim(strsplit(x, ',')), dval,...
                'UniformOutput', false);

            % // Update UI query-elements in tab-object
            for kk = 1:numel(qry)
                % Add label text
                app.tabdata.(tname).label(kk).Text = [qry{kk}];

                %  Convert non-char to string if necessary - and
                %  maintain a string-format for non-integer decimals
                vv = dval{kk};
                switch uitype{kk}
                    case 'popup'  
                        % Set inputobject value
                        app.tabdata.(tname).inputobj(kk).Items = vv;                              

                    case 'edit'                   
                        % No cell input - only for popup
                        if iscell(vv), vv = vv{:}; end

                        % Set inputobject value
                        app.tabdata.(tname).inputobj(kk).Value = vv;         
                end      
            end

        end
       
        function tab_initialise_ui(app, tname, opts)
            % Initialise the default tab UI-elements according to #query 
            % elements required: query_types contains popup or edit for
            % every query!
            %
            % Uses: , tab_initialise_uigrid(), tab_initialise_cosmetics() 
            % and tab_initialise_scrollbar()

            % Input object style via opts input (struct or q-types)
            if ~isstruct(opts), query_types = opts;
            else    
                query_types = {};
                if isfield(opts, 'uistyle')
                    query_types = {opts(:).uistyle}; 
                end
            end
            nquery = numel(query_types);

            % Calculate correct height of tab-area and create position
            % vectors for label and input objects
            tab_initialise_uigrid(app, tname, nquery);           
            
            % Load in tab grid position details.
            tgrid = app.tabdata.(tname).tgrid; % here read-only
            
            % Add title-label.
            app.tabdata.(tname).tl = uilabel(app.tabdata.(tname).tab,...
                'Visible', 'on', 'Enable','on', 'Text', '',... 
                'Position', tgrid.pos.title, 'BackgroundColor', app.clr.bg,...
                'FontColor', app.clr.fg);

            % Add the objects to the tab-obj
            for qi = 1:nquery
                % Add label & set position
                app.tabdata.(tname).label(qi) = uilabel(...
                    app.tabdata.(tname).tab, ...
                    'Position', tgrid.pos.label(qi,:),...
                    'BackgroundColor', app.clr.bg,...
                    'Text', '...initialising...');
                
                % Add inputobj & set position
                switch query_types{qi}
                    case 'popup'
                    app.tabdata.(tname).inputobj(qi) = uidropdown(...
                        app.tabdata.(tname).tab, ...
                        'Position', tgrid.pos.inputobj(qi,:));
                                                       
                    case 'edit'
                    app.tabdata.(tname).inputobj(qi) = uieditfield(...
                        app.tabdata.(tname).tab, ...
                        'Position', tgrid.pos.inputobj(qi,:));                                      
                end
            end

            % Initialise scrollbar.
            tab_initialise_scrollbar(app, tname)            

            % Cosmetics of all object
            tab_initialise_cosmetics(app, tname)
            
        end
           
        function tab_initialise_uigrid(app, tname, nquery)
            % The tab settings layout is calculated here and includes: 
            %   required tab-height, position vectors for query-elements.
            % A single query-element consist out of a label and input 
            % object. 
            
            % Will contain all details for this tab!
            tgrid = struct; 

            % Get tab object using tname
            tabobj = app.tabdata.(tname).tab;
            
            % Tab-width
            tabw = tabobj.Position(3) - 4; % Minus tab-border
            
            % These are default pixel heights of a edit i.e. inputobject 
            % and label obj
            sz_inputobj = 23; sz_label = 20; sz_title = 3*sz_label;
            
            % Distances between..
            dy = 6;     % query elements            
            bdx = 20;   % left/right tab-border
            bdy = 20;   % top/down tab-border

            % Size of single query-element: edit and label + offset
            sz_combined = sz_inputobj + sz_label + dy;
                        
            % Required tab-height: 
            % combined-size of query-element x query + offset to borders +
            % title-label
            tgrid.height = (sz_combined * nquery) + (sz_title + dy) + (2*bdy) - dy ;
            
            % Dependent on required tab-size - calculate position vectors
            % for all objects
            th_real = tgrid.height;
            if tgrid.height < tabobj.Position(4)
                th_real = tabobj.Position(4); % Real tab-height
            end

            % Height limit used to calculate position of title-label and
            % input-elements.
            pvec_lim = th_real - (sz_title + bdy + dy) ;
        
            % Position vectors for label and input-objects: top to bottom.
            pvec_label = pvec_lim:-sz_combined:(bdy+sz_inputobj);
            pvec_edit = pvec_label - sz_label - (0.75*dy);            

            % Define tab-width
            scrollbar_pixels = 0; % 17pixels is a safety for a scrollbar
            if tgrid.height > tabobj.Position(4), scrollbar_pixels = 17;end
            twidth = tabw -(bdx*2) - scrollbar_pixels; 

            % Create pos vec for title label
            title_ypos = th_real - bdy - sz_title;
            tgrid.pos.title = [bdx title_ypos twidth sz_title];  

            % Create pos vec for inputobj
            tgrid.pos.inputobj = cell2mat(arrayfun(@(yy)...
                [bdx, yy, twidth, sz_inputobj], pvec_edit,...
                'UniformOutput', false)');

            % Create pos vec for labels
            tgrid.pos.label = cell2mat(arrayfun(@(yy) ...
                [bdx, yy, twidth, sz_label], pvec_label,...
                'UniformOutput', false)');
                 
            % // --- Store all size-details in output struct.
            tgrid.sz.inputobj = sz_inputobj; tgrid.sz.label = sz_label;
            tgrid.sz.title = sz_title; tgrid.sz.combined = sz_combined; 
            tgrid.sz.tabw = tabw; tgrid.sz.twidth = twidth;
            tgrid.sz.bdy = bdy; tgrid.sz.bdx = bdx; tgrid.sz.dy = dy;

            % Store tab grid details
            app.tabdata.(tname).tgrid = tgrid;
        end
    
        function tab_initialise_scrollbar(app, tname)
            % Enable scrolling if required tab-height > tabgroup-height
            % FORCE height by invisible obj or something?
            
            % Get tab object using tname
            tabobj = app.tabdata.(tname).tab; 
            % Tab grid details (read-only)
            tgrid = app.tabdata.(tname).tgrid; 
    
            if tgrid.height > tabobj.Position(4)
                % Make tab-scrollable
                tabobj.Scrollable = 'on';
                
                % < Trick >
                % Add an extra label to force correct virtual tab-height
                % i.e. the offset from top to first uiobj (title label).
                pos = [tgrid.sz.bdx tgrid.height-tgrid.sz.bdy ...           % x and y
                       tgrid.sz.twidth tgrid.sz.bdy];                       % w and h
                app.tabdata.(tname).trick_label = uilabel(tabobj,...
                    'Visible', 'on', 'Enable','on', 'Text', '',... 
                    'Position', pos, 'BackgroundColor', app.clr.bg,...
                    'FontColor', app.clr.bg);
            end
        end

        function tab_initialise_cosmetics(app, tname)
            % Title label
            
            % Minor cosmetics.
            app.tabdata.(tname).tl.FontName = 'Consolas';
            app.tabdata.(tname).tl.HorizontalAlignment = 'left';
            app.tabdata.(tname).tl.VerticalAlignment = 'top';
            app.tabdata.(tname).tl.WordWrap = 1;

            % Only if label and input objects are avaible - continue with
            % cosmetics.
            if ~isfield(app.tabdata.(tname), 'label'), return; end

            % Label objects available - set cosmetics.
            for kk = 1:numel(app.tabdata.(tname).label)
                app.tabdata.(tname).label(kk).BackgroundColor = app.clr.bg;
                app.tabdata.(tname).label(kk).FontColor = app.clr.fg;
                app.tabdata.(tname).label(kk).FontWeight = 'bold';
            end

            % Set all input object colors 
            for kk = 1:numel(app.tabdata.(tname).inputobj)
                app.tabdata.(tname).inputobj(kk).BackgroundColor = app.clr.bg;
                app.tabdata.(tname).inputobj(kk).FontColor = app.clr.fg;
            end
        end

        % \\ OPTS Functions ------------------------------------- %
        % ------------------------------------------------------- %

        % opts_parse
        % config2opts --> use config file NOT gui_methods :) Means we can
        % use it in scripted form without GUI later on.

        % // --- Convert details in methods-tabs to options struct.
        function opts = tab2opts(app)
        % Returns structure opts with a field for every method found in 
        % the tabgroup. 
        % 
        % The method field is of size 1xN options required for the callback
        % of said method. The user input is parsed according details in
        % config_methods.txt as loaded into app.gui_methods.
        %
        % Opts format for methods with input-arguments:
        %      opts.(method_name)(1:#options)
        %               .varname     parameters name for the callback
        %               .val        parameter value        
        % Opts format for methods without input-arguments:
        %       opts.(method_name) = []
                            
        % Set output struct;
        opts = struct;

        flds = fieldnames(app.tabdata);  % Tab names in database
        flds(strcmp(flds, 'Data')) = []; % Remove data-tab entry
        for ti = 1:numel(flds) % Methods loop
            tname = flds{ti};               % Method tab name            
            tdata = app.tabdata.(tname);    % Method tab data           
            mnfo = app.gui_methods.(tname); % Method database nfo

            % Check for inputobj i.e. opts
            if isfield(tdata, 'inputobj')  
                for pp = 1:numel(tdata.inputobj) % Options-loop

                    % Get user-input in input-object.
                    val = tdata.inputobj(pp).Value;         
                    
                    % If dropdown/popup, check for items
                    if isprop(tdata.inputobj(pp), 'Items') 
                        
                        % Selected index in popup-list (logical)
                        indx = strcmp(tdata.inputobj(pp).Items, val); 

                        % Use mfo to parse input-data         
                        cnfg_inp = strsplit(mnfo.opts(pp).input, ',');
                        switch mnfo.opts(pp).type
                            case 'int'                                
                                cnfg_inp = int64(str2double(cnfg_inp));
                            case strcmp(mnfo.opts(pp).type, 'float')
                                cnfg_inp = str2double(cnfg_inp);                            
                        end
                        % Value for parameter in correct data-format
                        val = cnfg_inp(indx);

                    else
                        % Value from uieditfield.
                        switch mnfo.opts(pp).type
                            case 'int',     val = int64(val);
                            case 'float',   val = double(val);
                        end
                        % Otherwise it is a string!
                    end
                    
                    % Store opts parameters
                    opts.(tname)(pp).varname = mnfo.opts(pp).varname;
                    opts.(tname)(pp).val = val;

                end % End options-loop
            else
                opts.(tname) = []; % No input options to set.
            end
            
        end % End methods-loop


        end


        % \\ LOG Functions -------------------------------------- %
        % ------------------------------------------------------- %

        function app = memrigui_log(app, varargin)     
        % Writes any strings and most integer lists to log-data. If
        % memri-data is loaded it will write to log-file and display in
        % GUI.

            % Check if data-str is loaded and full app-data available.
            if ~isempty(app.memri) && isfield(app.memri, 'data')
                % Set log-data in memri-struct;
                app.memri = memri_log(app.memri, varargin{:});
    
                % Update listbox log display
                memrigui_log_listbox_update(app);  
            else
                
                % First time log-generation: LOG User + GUI; there is no 
                % appdata struct available.
                if ~isfield(app.memri,'log')
                    app.memri.log = app.ListBox_Log.Items;
                    app.memri.log = cat(1,app.memri.log(:), varargin(:));
                end
    
                % Create log with general MeMRI PTB log-function
                %  \* = Do  Not Save To File
                app.memri = memri_log(app.memri, '\*', ...
                    'GUI-Log synced with MeMRI-PTB.');    
    
                % Update listbox log display
                memrigui_log_listbox_update(app);
                
            end            
                % Update graphics otherwise MATLAB can execute all functions
                % first before showing log-updates in listbox object.
                drawnow;

        end
       
        function memrigui_log_listbox_update(app)            
            if ~isequal(app.ListBox_Log.Items, app.memri.log)
                app.ListBox_Log.Items = flip(app.memri.log, 1);
                app.ListBox_Log.ValueIndex = 1;
                drawnow;
            end                
        end

        % \\ File and Data handling ----------------------------- %
        % ------------------------------------------------------- %
      
        function [fn, fp] = memrigui_file_select(app)
        % Opens file-select UI and set filepath in app.filepath.

            % Select file(s) to load
            [fn, fp] = uigetfile({'*.mat; mat-file'}, ...
                'MeMRI PTB GUI: Select MeMRI data.');    
            app.filepath = [fp fn];
            
        end

        function memrigui_file_read_mat(app)            
            % Load mat-file into app.memri.         
            if ~isempty(app.filepath)
                app.memri.input = load(app.filepath); 
            end
        end
        
        function memrigui_file_parse_memri(app)
            % Check if loaded mat-file struct in app.memri.(XXX) is equal
            % "memri" and load into GUI memory properly. Start logging and
            % set GUI.
            
            % Store loaded memri-struct
            if ~isempty(app.memri) && isfield(app.memri, 'input')

                % Save log of GUI
                doLog = 0;
                if isfield(app.memri, 'log') && isfield(app.memri, 'instance')                    
                    log = app.memri.log; instance = app.memri.instance;
                    doLog = 1;
                end
                
                % // Store MeMRI-struct data only
                fld = fieldnames(app.memri.input);
                app.memri = app.memri.input.(fld{:}); % Set appdata.   

                % Restore log and set the new instance (related to
                % MeMRI-PTB GUI)
                if doLog 
                    if isfield(app.memri, 'log')
                        app.memri.log = cat(1, app.memri.log(:), log(:));
                    else
                        app.memri.log = log; % GUI log only.
                    end
                    app.memri.instance = instance;
                end

                % Update filepath field
                % Filepath in memri-struct can be a different host - if
                % path is not found on current system, use loaded mat-file
                % as location.
                [fp, fn, ext] = fileparts(app.memri.filepath);
                if exist(fp, 'dir') ~= 7    
                    fp = fileparts(app.filepath);
                    app.memri.filepath = [fp '\' fn ext];
                end            

                % LOG to log-file
                memrigui_log(app, 'MeMRI data-struct loaded into memory.');  
                                 
                % Update GUI
                [~, fn , ext] = fileparts(app.memri.filepath);
                app.Edit_File.Value = [fn ext];
            end
        end
        
        % - Executed to select, load and parse a file.
        function memrigui_file_open(app, doSelect)
        % Select a file via ui, read the data and parse the content.
        % Executed by mb: Main > Open...
            
            % Use selection UI if arg is missing
            if nargin < 2, doSelect = 1; end
            % Select file and log: set app.filepath
            if doSelect, memrigui_file_select(app); end

            % Log & busy-state
            [fp,fn,ext] = fileparts(app.filepath);
            memrigui_log(app, ['File: ' fn ext], ['Path: ' fp],...
                'Loading data...');
            memrigui_lamp_toggle(app, 1); 

            % Read mat-file
            memrigui_file_read_mat(app);
            
            % Parse memri-struct
            memrigui_file_parse_memri(app);                        
            
            % Show busy state
            memrigui_lamp_toggle(app, 0); 

            % Initialise tab for data!
            TabGroup_Settings_Initialise(app);
        end
                
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Load available methods
            methods_initialise(app)
                       
            % Automatically load for < dev >
            % drawnow; Edit_FileValueChanged(app);

        end

        % Button pushed function: Button_Method_Add
        function Button_Method_AddPushed(app, event)
            
            %% Add Item

            % Remove empty lines in listbox
            ind_empty = cellfun(@(x) isempty(x), ...
                app.ListBox_Methods_Apply.Items);
            
            % Current list of methods to apply
            methods_apply = app.ListBox_Methods_Apply.Items(~ind_empty);          
            
            % Items to add
            methods_add = app.ListBox_Methods_List.Value;

            % Filter duplicates
            ind_dupes = cellfun(@(x) strcmp(x, methods_add),...
                methods_apply, 'UniformOutput', false);  
            ind_dupes = sum(cell2mat(ind_dupes'),1);
            methods_add(logical(ind_dupes)) =  [];

            % Add entries to lb
            if ~isempty(methods_add)
                app.ListBox_Methods_Apply.Items = cat(2,methods_apply, ...
                    methods_add);
            end

            %% Update Parameters tab window
            TabGroup_Settings_Initialise(app);

        end

        % Button pushed function: Button_Processing
        function Button_ProcessingPushed(app, event)
            % Read the current list of operations and start executing each
            % in order of the list.
            
            % Enable busy lamp
            memrigui_lamp_toggle(app, 1);

            % Get list of operations to apply
            methods_apply = app.ListBox_Methods_Apply.Items;
            
            % < Here we can apply logic to the order >
            % Put it in a seperate function though!   
            % < ------------------------------------ >
     
            % < Get callbacks for methods and options >                                         
            % Variable names for the methods
            vnames = cellfun(@(x) genVarName(x), methods_apply, ...
                'UniformOutput', false);
            % Get callbacks for the methods: @method_callback();
            callbacks = cellfun(@(x) app.gui_methods.(x).function, vnames,...
                'UniformOutput', false);
            callbacks = cellfun(@(x) x(2:end-3), callbacks,...
                'UniformOutput', false); % Remove @();
            
            % Get options
            opts = tab2opts(app);
            % < ------------------------------------ >

            % LOG
            str_moi = strjoin(methods_apply, ' / ');
            memrigui_log(app, ['Applying methods: ', str_moi]);
            drawnow;
            for mi = 1:numel(methods_apply)
                
                % Log
                memrigui_log(app, ['Executing method: ' methods_apply{mi}]);

                % Check for options.
                if isfield(opts.((vnames{mi})),'varname')

                    % Options name and value
                    copts_varname = {opts.(vnames{mi})(:).varname};
                    copts_value = {opts.(vnames{mi})(:).val};
                    % Convert to cell-list
                    copts = cat(2, copts_varname', copts_value')';
                    copts = [copts(:)];
                    
                    % Proper log-display of options
                    copts_log = cell(1,numel(copts)./2);
                    for kk = 1:2:numel(copts)
                        % Input variable value
                        dval = copts{kk+1};
                        % Convert to string
                        if ~ischar(dval), dval = int2str(copts{kk+1});end
                        % 
                        copts_log{round(kk./2)} = [copts{kk} ': ' dval];
                    end
                    copts_log = strjoin(copts_log, ' | ');
                    memrigui_log(app, 'Using options: ', copts_log);

                    % Execute method callback
                    app.memri = feval(callbacks{mi}, app.memri, ...
                        copts{:});
                else
                    app.memri = feval(callbacks{mi}, app.memri);
                end
        
                % Update gui log with log from method callback
                memrigui_log_listbox_update(app);
            end
                              
            % Disable busy lamp
            memrigui_lamp_toggle(app, 0);

        end

        % Button pushed function: Button_Method_Remove
        function Button_Method_RemovePushed(app, event)
            
            % Get list of items to delete from listbox
            del = app.ListBox_Methods_Apply.Value;
            ind_del = cellfun(@(x) strcmp(app.ListBox_Methods_Apply.Items,x),...
                del, 'UniformOutput',false);
            ind_del = sum(cell2mat(ind_del'),1);
            app.ListBox_Methods_Apply.Items(logical(ind_del)) = [];

            % Remove tab-data exists for this method
            % TabGroup_Settings_CleanUp
            for kk = 1:numel(del)
                tname = genVarName(del{kk});
                if isfield(app.tabdata, tname)
                    delete(app.tabdata.(tname).tab); % Delete gfx
                    app.tabdata = rmfield(app.tabdata, tname); % Remove field
                end
            end

        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            memrigui_reset(app);
            memrigui_file_open(app);
        end

        % Value changed function: Edit_File
        function Edit_FileValueChanged(app, event)
            
            value = strrep(app.Edit_File.Value, '"', '');
            if isfile(value)
                % Reset GUI
                memrigui_reset(app);

                [~, ~, ext] = fileparts(value);
                if strcmp(ext,'.mat')
                    app.filepath = value; memrigui_file_open(app, 0);
                end
            end                                  
        end

        % Button pushed function: Button_File
        function Button_FilePushed(app, event)
            memrigui_reset(app);
            memrigui_file_open(app);
        end

        % Button pushed function: Button_View_Data
        function Button_View_DataPushed(app, event)
            CSIgui(app.memri.data, 'mrs', app.memri.labels, 'labels');
        end

        % Button pushed function: Button_ListBox_Methods_Apply_Up
        function Button_ListBox_Methods_Apply_UpPushed(app, event)
            % Move selected listbox items up. Supports multi-select.

            % lbox data
            mlist = app.ListBox_Methods_Apply.Items;
            mselected = app.ListBox_Methods_Apply.Value;
            
            % Index(es) of selected item(s)
            ind = zeros(1,numel(mselected));
            for si = 1:numel(mselected)
                tmp = cell2mat(cellfun(@(x) strcmp(x, mselected(si)), mlist,...
                           'UniformOutput', false)); 
                ind(si) = find(tmp == 1);
            end
            ind = sort(ind); % Ensure sel. items from top to bottom.
           
            % Move-up ceiling safety
            if ind(1) == 1
                for ii = 1:numel(ind)
                    if numel(ind) > ii && ind(ii+1) == ii+1
                        ind(ii+1) = 1;
                    end
                end
            end
            
            % Move item(s) up iteratively
            if ~isequal(ind, 1:numel(mselected))
                for si = 1:numel(ind)
                    if ind(si) ~= 1
                        mlist([ind(si)-1 ind(si)]) = mlist([ind(si) ind(si)-1]); 
                        app.ListBox_Methods_Apply.Items = mlist;                 
                    end
                end
            end

            % Update tabgroup settings
            TabGroup_Settings_update(app);

        end

        % Selection change function: TabGroup_Settings
        function TabGroup_SettingsSelectionChanged(app, event)
     
            tstr = genVarName(app.TabGroup_Settings.SelectedTab.Title);
            if isfield(app.tabdata, tstr) && ...
                    isfield(app.tabdata.(tstr), 'inputobj')
                focus(app.tabdata.(tstr).inputobj(1)); 
            end
        end

        % Button pushed function: Button_View_Parameters
        function Button_View_ParametersPushed(app, event)
            assignin("base","app", app);
        end

        % Button pushed function: Button_ListBox_Methods_Apply_Down
        function Button_ListBox_Methods_Apply_DownPushed(app, event)
            % Move selected listbox items up. Supports multi-select.

            % lbox data
            mlist = app.ListBox_Methods_Apply.Items;
            mselected = app.ListBox_Methods_Apply.Value;
            
            % Index(es) of selected item(s)
            ind = zeros(1,numel(mselected));
            for si = 1:numel(mselected)
                tmp = cell2mat(cellfun(@(x) strcmp(x, mselected(si)), mlist,...
                           'UniformOutput', false)); 
                ind(si) = find(tmp == 1);
            end
            % Ensure sel. items from bottom to top
            ind = sort(ind, 'descend'); 
           
            % Move-down ceiling safety
            if ind(1) == numel(mlist)
                for ii = 1:numel(ind)
                    if numel(ind) > ii && ind(ii+1) == numel(mlist)-ii
                        ind(ii+1) = numel(mlist);
                    end
                end
            end
            
            % Move item(s) up iteratively
            if ~isequal(ind, 1:numel(mselected))
                for si = 1:numel(ind)
                    if ind(si) ~= numel(mlist)
                        mlist([ind(si) ind(si)+1]) = mlist([ind(si)+1 ind(si)]); 
                        app.ListBox_Methods_Apply.Items = mlist;                 
                    end
                end
            end

            % Update tabgroup settings
            TabGroup_Settings_update(app);

        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            MeMRIProcessingToolboxUIFigureCloseRequest(app, event);
        end

        % Close request function: MeMRIProcessingToolboxUIFigure
        function MeMRIProcessingToolboxUIFigureCloseRequest(app, event)
            delete(app);            
        end

        % Menu selected function: ConverterMenu
        function ConverterMenuSelected(app, event)
            memri_DataConverter_GUI();
        end

        % Button pushed function: Button_MethodsPreset_Export
        function Button_MethodsPreset_ExportPushed(app, event)
            
            % Methods of interest
            moi = app.ListBox_Methods_Apply.Items;
            
            % Get filepath to start UI at.
            fp = pwd;
            if isfield(app.memri, 'filepath'), fp = app.memri.filepath; end            

            % Get file UI
            [fn, fp, idx] = uiputfile({'*.txt;','Text File'},...
                'Export methods preset..', fp);
            if idx == -1, return; end

            % Save to file
            status = writeText([fp fn], moi);
            if isnan(status)
                memri_log(app.memri, 'Failed saving methods-preset.');
                return;
            end
                
           
        end

        % Button pushed function: Button_MethodsPreset_Default
        function Button_MethodsPreset_DefaultPushed(app, event)
            
            % Read the default methods preset.
            fpn = [pwd '\Presets\Methods_Preset_Default.txt'];

            % Check for default-file existance
            if ~exist(fpn, 'file')                
                memrigui_log(app, ...
                    'Could not find default methods preset text-file:',...
                    fpn);
                return;
            end

            % Load data
            moi = readText(fpn);

            % Show in GUI
            app.ListBox_Methods_Apply.Items = moi;

            % Update GUI!
            TabGroup_Settings_Initialise(app);

        end

        % Button pushed function: Button_File_Reload
        function Button_File_ReloadPushed(app, event)
            if ~isempty(app.filepath)
                % Remember filepath to file
                fpn = app.filepath;

                % Reset GUI
                memrigui_reset(app);

                % Reload file
                app.filepath = fpn; memrigui_file_open(app, 0);                               
            end
        end

        % Button pushed function: Button_MethodsPreset_Import
        function Button_MethodsPreset_ImportPushed(app, event)
            
            % // --- User want to load a methods preset text file
            
            % Get filepath to start fileselect UI at.
            fp = pwd;
            if isfield(app.memri, 'filepath'), fp = app.memri.filepath; end          

            % Get file
            [fn, fp, idx] = uigetfile({'*txt','Text-file'},...
                'Select methods preset text-file...', fp);
            if idx == -1, return; end

            % Load file
            moi =  readText([fp fn]);

            % Set methods
            app.ListBox_Methods_Apply.Items = moi;

            % Update GUI
            TabGroup_Settings_Initialise(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MeMRIProcessingToolboxUIFigure = uifigure('Visible', 'off');
            app.MeMRIProcessingToolboxUIFigure.Name = 'MeMRI Processing Toolbox';
            app.MeMRIProcessingToolboxUIFigure.CloseRequestFcn = createCallbackFcn(app, @MeMRIProcessingToolboxUIFigureCloseRequest, true);
            app.mb_Main = uimenu(app.MeMRIProcessingToolboxUIFigure);
            app.mb_Main.Text = 'Main';
            app.OpenMenu = uimenu(app.mb_Main);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Tooltip = {'Open a mat-file.'};
            app.OpenMenu.Text = 'Open...';
            app.ConverterMenu = uimenu(app.mb_Main);
            app.ConverterMenu.MenuSelectedFcn = createCallbackFcn(app, @ConverterMenuSelected, true);
            app.ConverterMenu.Text = 'Converter';
            app.ImportMenu_3 = uimenu(app.mb_Main);
            app.ImportMenu_3.Text = 'Import';
            app.NoiseDataMenu = uimenu(app.ImportMenu_3);
            app.NoiseDataMenu.Text = 'Noise Data';
            app.MRImagesMenu_2 = uimenu(app.ImportMenu_3);
            app.MRImagesMenu_2.Text = 'MR Images';
            app.ProtocolParametersMenu = uimenu(app.ImportMenu_3);
            app.ProtocolParametersMenu.Text = 'Protocol Parameters';
            app.MethodsMenu = uimenu(app.mb_Main);
            app.MethodsMenu.Text = 'Methods';
            app.ImportMenu = uimenu(app.MethodsMenu);
            app.ImportMenu.Text = 'Import';
            app.ExportMenu = uimenu(app.MethodsMenu);
            app.ExportMenu.Text = 'Export';
            app.ExitMenu = uimenu(app.mb_Main);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.mb_Parameters = uimenu(app.MeMRIProcessingToolboxUIFigure);
            app.mb_Parameters.Text = 'Parameters';
            app.PresetMenu = uimenu(app.mb_Parameters);
            app.PresetMenu.Text = 'Preset...';
            app.ImportMenu_2 = uimenu(app.PresetMenu);
            app.ImportMenu_2.Text = 'Import';
            app.ExportMenu_2 = uimenu(app.PresetMenu);
            app.ExportMenu_2.Text = 'Export';
            app.mb_ViewerMenu = uimenu(app.MeMRIProcessingToolboxUIFigure);
            app.mb_ViewerMenu.Text = 'Viewer';
            app.CSIguiMenu = uimenu(app.mb_ViewerMenu);
            app.CSIguiMenu.Text = 'CSIgui';
            app.ViewerMenu_2 = uimenu(app.mb_ViewerMenu);
            app.ViewerMenu_2.Text = 'Viewer';
            app.mb_Help = uimenu(app.MeMRIProcessingToolboxUIFigure);
            app.mb_Help.Text = 'Help';
            app.QuickGuideMenu = uimenu(app.mb_Help);
            app.QuickGuideMenu.Text = 'Quick Guide';
            app.TutorialMenu = uimenu(app.mb_Help);
            app.TutorialMenu.Text = 'Tutorial';
            app.UpdatesMenu = uimenu(app.mb_Help);
            app.UpdatesMenu.Text = 'Updates...';
            app.AboutMenu = uimenu(app.mb_Help);
            app.AboutMenu.Separator = 'on';
            app.AboutMenu.Text = 'About';
            app.BusyLamp = uilamp(app.MeMRIProcessingToolboxUIFigure);
            app.BusyLamp.Enable = 'off';
            app.ListBox_Methods_List = uilistbox(app.MeMRIProcessingToolboxUIFigure);
            app.ListBox_Methods_List.Items = {'Merge MRI', 'Apodization', 'Zero-filling'};
            app.ListBox_Methods_List.Multiselect = 'on';
            app.ListBox_Methods_List.Value = {'Merge MRI'};
            app.ListBox_Methods_Apply = uilistbox(app.MeMRIProcessingToolboxUIFigure);
            app.ListBox_Methods_Apply.Items = {''};
            app.ListBox_Methods_Apply.Multiselect = 'on';
            app.ListBox_Methods_Apply.Value = {''};
            app.ListBox_Log = uilistbox(app.MeMRIProcessingToolboxUIFigure);
            app.ListBox_Log.Items = {'Metabolic MRI Processing Toolbox GUI', ''};
            app.ListBox_Log.Value = 'Metabolic MRI Processing Toolbox GUI';
            app.Label_MethodsPreset = uilabel(app.MeMRIProcessingToolboxUIFigure);
            app.Label_MethodsPreset.Text = 'Methods Preset:';
            app.Label_Details = uilabel(app.MeMRIProcessingToolboxUIFigure);
            app.Label_Details.Text = 'Details';
            app.Label_ParametersPreset = uilabel(app.MeMRIProcessingToolboxUIFigure);
            app.Label_ParametersPreset.Text = 'Parameters Preset:';
            app.Label_Methods = uilabel(app.MeMRIProcessingToolboxUIFigure);
            app.Label_Methods.Text = 'Methods:';
            app.Label_Operations = uilabel(app.MeMRIProcessingToolboxUIFigure);
            app.Label_Operations.Text = 'Operations:';
            app.Button_Processing = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_Processing.ButtonPushedFcn = createCallbackFcn(app, @Button_ProcessingPushed, true);
            app.Button_Processing.Text = 'Start Processing';
            app.Button_ParametersPreset_Export = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_ParametersPreset_Export.Text = 'Export';
            app.Button_ParametersPreset_Import = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_ParametersPreset_Import.Text = 'Import';
            app.Button_ParametersPreset_Default = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_ParametersPreset_Default.Text = 'Default';
            app.Button_MethodsPreset_Export = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_MethodsPreset_Export.ButtonPushedFcn = createCallbackFcn(app, @Button_MethodsPreset_ExportPushed, true);
            app.Button_MethodsPreset_Export.Tooltip = {'Export selected methods to text-file.'};
            app.Button_MethodsPreset_Export.Text = 'Export';
            app.Button_MethodsPreset_Import = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_MethodsPreset_Import.ButtonPushedFcn = createCallbackFcn(app, @Button_MethodsPreset_ImportPushed, true);
            app.Button_MethodsPreset_Import.Tooltip = {'Import methods preset text-file.'};
            app.Button_MethodsPreset_Import.Text = 'Import';
            app.Button_MethodsPreset_Default = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_MethodsPreset_Default.ButtonPushedFcn = createCallbackFcn(app, @Button_MethodsPreset_DefaultPushed, true);
            app.Button_MethodsPreset_Default.Tooltip = {'Rea in default list of methods to apply to CSI data.'};
            app.Button_MethodsPreset_Default.Text = 'Default';
            app.Button_View_Parameters = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_View_Parameters.ButtonPushedFcn = createCallbackFcn(app, @Button_View_ParametersPushed, true);
            app.Button_View_Parameters.Text = 'View';
            app.Button_Method_Add = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_Method_Add.ButtonPushedFcn = createCallbackFcn(app, @Button_Method_AddPushed, true);
            app.Button_Method_Add.Tooltip = {'Add method(s) as a processing step(s) to the "Operations" list.'};
            app.Button_Method_Add.Text = '→';
            app.Button_Method_Remove = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_Method_Remove.ButtonPushedFcn = createCallbackFcn(app, @Button_Method_RemovePushed, true);
            app.Button_Method_Remove.Tooltip = {'Remove selected processing method(s) in "Operations" list. '};
            app.Button_Method_Remove.Text = 'X';
            app.Button_View_Data = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_View_Data.ButtonPushedFcn = createCallbackFcn(app, @Button_View_DataPushed, true);
            app.Button_View_Data.Text = 'View';
            app.Button_ListBox_Methods_Apply_Up = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_ListBox_Methods_Apply_Up.ButtonPushedFcn = createCallbackFcn(app, @Button_ListBox_Methods_Apply_UpPushed, true);
            app.Button_ListBox_Methods_Apply_Up.Text = '↑';
            app.Button_ListBox_Methods_Apply_Down = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_ListBox_Methods_Apply_Down.ButtonPushedFcn = createCallbackFcn(app, @Button_ListBox_Methods_Apply_DownPushed, true);
            app.Button_ListBox_Methods_Apply_Down.Text = '↓';
            app.Button_File = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_File.ButtonPushedFcn = createCallbackFcn(app, @Button_FilePushed, true);
            app.Button_File.Tooltip = {'Open a mat-file.'};
            app.Button_File.Text = 'File:';
            app.TabGroup_Settings = uitabgroup(app.MeMRIProcessingToolboxUIFigure);
            app.TabGroup_Settings.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_SettingsSelectionChanged, true);
            app.ProcessingTab = uitab(app.TabGroup_Settings);
            app.ProcessingTab.Title = 'Processing';
            app.tree_methods_kspace_4 = uitree(app.ProcessingTab, 'checkbox');
            app.OverwriteNode = uitreenode(app.tree_methods_kspace_4);
            app.OverwriteNode.Text = 'Overwrite';
            app.ExportPresetNode = uitreenode(app.tree_methods_kspace_4);
            app.ExportPresetNode.Text = 'Export Preset';
            app.WhenfinishedNode = uitreenode(app.tree_methods_kspace_4);
            app.WhenfinishedNode.Text = 'When finished...';
            app.ShutdownNode = uitreenode(app.WhenfinishedNode);
            app.ShutdownNode.Text = 'Shutdown';
            app.OpeninCSIguiNode = uitreenode(app.WhenfinishedNode);
            app.OpeninCSIguiNode.Text = 'Open in CSIgui';
            app.OpendirectoryNode = uitreenode(app.WhenfinishedNode);
            app.OpendirectoryNode.Text = 'Open directory';
            % Assign Checked Nodes
            app.tree_methods_kspace_4.CheckedNodes = [app.OpeninCSIguiNode];
            app.BusyLampLabel = uilabel(app.MeMRIProcessingToolboxUIFigure);
            app.BusyLampLabel.Text = 'Busy';
            app.Edit_File = uieditfield(app.MeMRIProcessingToolboxUIFigure, 'text');
            app.Edit_File.ValueChangedFcn = createCallbackFcn(app, @Edit_FileValueChanged, true);
            app.Edit_File.Tooltip = {'Enter the full filepath and press any key to load a file (or use the menu).'};
            app.Edit_File.Value = 'D:\OneDrive\Matlab\Metabolic-MRI-Processing-Toolbox\_ExampleData\CSI_Philips\raw_019.mat';
            app.Button_File_Reload = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_File_Reload.ButtonPushedFcn = createCallbackFcn(app, @Button_File_ReloadPushed, true);
            app.Button_File_Reload.Tooltip = {'Reset and reload the initial mat-file.'};
            app.Button_File_Reload.Text = '⟳';
            % Show the figure after all components are created
            app.MeMRIProcessingToolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MeMRI_Processing_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MeMRIProcessingToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MeMRIProcessingToolboxUIFigure)
        end
    end
end