classdef memri_Processing_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure_MeMRI_PTB              matlab.ui.Figure
        mb_main                         matlab.ui.container.Menu
        mb_main_open                    matlab.ui.container.Menu
        mb_main_converter               matlab.ui.container.Menu
        mb_main_export                  matlab.ui.container.Menu
        mb_main_export_data             matlab.ui.container.Menu
        mb_main_import                  matlab.ui.container.Menu
        mb_main_import_noise            matlab.ui.container.Menu
        mb_main_import_images           matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        mb_options                      matlab.ui.container.Menu
        mb_options_methods              matlab.ui.container.Menu
        mb_options_methods_import       matlab.ui.container.Menu
        mb_options_methods_export       matlab.ui.container.Menu
        mb_options_parameters           matlab.ui.container.Menu
        mb_options_parameters_import    matlab.ui.container.Menu
        mb_options_parameters_export    matlab.ui.container.Menu
        mb_options_processing           matlab.ui.container.Menu
        mb_options_processing_export_data  matlab.ui.container.Menu
        mb_options_processing_export_data_automatic  matlab.ui.container.Menu
        mb_options_processing_export_data_custom_location  matlab.ui.container.Menu
        mb_options_processing_export_data_overwrite  matlab.ui.container.Menu
        mb_options_processing_export_preset  matlab.ui.container.Menu
        mb_options_processing_after     matlab.ui.container.Menu
        mb_options_processing_after_open_directory  matlab.ui.container.Menu
        mb_options_processing_after_open_in_viewer  matlab.ui.container.Menu
        mb_options_processing_after_shutdown  matlab.ui.container.Menu
        mb_viewer                       matlab.ui.container.Menu
        mb_viewer_csigui                matlab.ui.container.Menu
        mb_viewer_viewer                matlab.ui.container.Menu
        mb_Help                         matlab.ui.container.Menu
        QuickGuideMenu                  matlab.ui.container.Menu
        TutorialMenu                    matlab.ui.container.Menu
        UpdatesMenu                     matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        Button_File_Reload              matlab.ui.control.Button
        Edit_File                       matlab.ui.control.EditField
        BusyLampLabel                   matlab.ui.control.Label
        TabGroup_Details                matlab.ui.container.TabGroup
        ProcessingTab                   matlab.ui.container.Tab
        NodeTree_Processing             matlab.ui.container.CheckBoxTree
        ExportDataNode                  matlab.ui.container.TreeNode
        AutomaticNode                   matlab.ui.container.TreeNode
        CustomlocationNode              matlab.ui.container.TreeNode
        OverwriteNode_2                 matlab.ui.container.TreeNode
        ExportPresetNode                matlab.ui.container.TreeNode
        AfterprocessingNode             matlab.ui.container.TreeNode
        OpendirectoryNode               matlab.ui.container.TreeNode
        OpeninViewerNode                matlab.ui.container.TreeNode
        ShutdownNode_2                  matlab.ui.container.TreeNode
        Button_File                     matlab.ui.control.Button
        Button_ListBox_Methods_Down     matlab.ui.control.Button
        Button_ListBox_Methods_Up       matlab.ui.control.Button
        Button_View_Data                matlab.ui.control.Button
        Button_Method_Remove            matlab.ui.control.Button
        Button_Method_Add               matlab.ui.control.Button
        Button_View_Parameters          matlab.ui.control.Button
        Button_MethodsPreset_Default    matlab.ui.control.Button
        Button_MethodsPreset_Import     matlab.ui.control.Button
        Button_MethodsPreset_Export     matlab.ui.control.Button
        Button_ParametersPreset_Default  matlab.ui.control.Button
        Button_ParametersPreset_Import  matlab.ui.control.Button
        Button_ParametersPreset_Export  matlab.ui.control.Button
        Button_Processing               matlab.ui.control.Button
        Label_Operations                matlab.ui.control.Label
        Label_Methods                   matlab.ui.control.Label
        Label_ParametersPreset          matlab.ui.control.Label
        Label_Details                   matlab.ui.control.Label
        Label_MethodsPreset             matlab.ui.control.Label
        ListBox_Log                     matlab.ui.control.ListBox
        ListBox_Methods_Apply           matlab.ui.control.ListBox
        ListBox_Methods_List            matlab.ui.control.ListBox
        BusyLamp                        matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        % Main memri data struct with all input and parameters.
        filepath = [];
    end
    
    properties (Access = public)
        memri = struct;         % Input data struct
        tabdata = struct;       % Tab-data structure for tabgroup
        gui_methods = struct;   % Methods storage

        % Colors used in GUI
        clr = struct('bg', [0.15 0.15 0.15], ...
                     'fg', [0.94 0.94 0.94], ...
                     'hl', [0.43 0.17 0.31]);
    end

    % Callbacks
    methods (Access = private)
        
        % \\ Default callbacks for MeMRI GUI -------------------- %
        % ------------------------------------------------------- %

        function memrigui_reset(app)
            % Clear log listbox 
            app.ListBox_Log.Items = ...
            {'% --- Metabolic MRI Processing ToolBox GUI v0.5 --- %',''};

            % Clear data-tab
            if isfield(app.tabdata, 'Data')
                delete(app.tabdata.Data.tab);
                app.tabdata = rmfield(app.tabdata, 'Data');
            end

            % Reset main filepath
            app.filepath = [];    

            % Reset memri-data struct in app
            app.memri = struct;

            % Lamp off
            memrigui_lamp_toggle(app, 0);
        end

        function memrigui_lamp_toggle(app, state)
            if state
                % Enable busy lamp
                app.BusyLamp.Color = 'g'; app.BusyLamp.Enable = 1;
            else
                % Disable busy lamp
                app.BusyLamp.Color = 'w'; app.BusyLamp.Enable = 0;
            end
            pause(0.005); 
        end

        function methods_initialise(app)
        % Read config file with all available methods, process the 
        % method options and enable in app.

            % Get all methods
            app.gui_methods = readMethods();
            if ~isstruct(app.gui_methods) && isnan(app.gui_methods)
                msg = ['Error loading methods module, please check github.',...
                    'See error log for details.'];
                memrigui_log(app,msg); warndlg(msg);
                return;
            end

            % Set available methods
            flds = fieldnames(app.gui_methods);
            mnames = cellfun(@(x) app.gui_methods.(x).name, flds, ...
                'UniformOutput', false);

            % Add available method modules to methods-list:
            app.ListBox_Methods_List.Items = ...
                cat(1, mnames(:), app.ListBox_Methods_List.Items(:));

        end


        % \\ MeMRI data Processing  Callbacks ------------------- %
        % ------------------------------------------------------- %
        
        function processing_initiate(app)            
        % Set up all required parameters and initiate processing of the
        % MR spectroscopy data using the processing methods selected by
        % the user.
       
         % Enable busy light
        memrigui_lamp_toggle(app, 1);

        % // --- Methods of Interest
        methods_apply = app.ListBox_Methods_Apply.Items;
        
        % If no methods have been selected.
        if isempty(methods_apply)
            memrigui_log(app, 'Processing aborted, no methods selected.')
            return; 
        end

        % < Here we can apply logic to the order >
        % eg Roemer before FFT whilst WSVD after. 
        % < ------------------------------------ >
                
        % < Get callbacks and options for methods >                                         
        % Variable names for the methods
        vnames = cellfun(@(x) genVarName(x), methods_apply, ...
            'UniformOutput', false);
        % Get callbacks for the methods: @method_callback();
        callbacks = cellfun(@(x) app.gui_methods.(x).function, vnames,...
            'UniformOutput', false);
        callbacks = cellfun(@(x) x(2:end-3), callbacks,...
            'UniformOutput', false); % Removes @();
        
        % // --- Method Options
        opts = tab2opts(app); 
        
        % Log which methods are active.
        str_moi = strjoin(methods_apply, ' / ');
        memrigui_log(app, ['Applying methods: ', str_moi]);
        drawnow;

        % // --- Processing Main
        app = processing_main(app, vnames, callbacks, opts);

        % // -- Clean up

        % Store options in memri-struct
        app.memri.opts = opts;

        % LOG
        memrigui_log(app,'All methods are executed. Processing finished.');
              
        % Disable busy light
        memrigui_lamp_toggle(app, 0);

        
        % // --- Apply post-processing settings set by user
        % < Data is saved to file here if enabled by user >
        processing_post(app);

        end

        function app = processing_main(app, vnames, callbacks, opts)
        % Run the main processing loop: execute the callbacks associated
        % with vnames using the options in the opts-struct.

            % Get list of operations to apply
            methods_apply = app.ListBox_Methods_Apply.Items;

            % Loop each method and apply to data.
            for mi = 1:numel(methods_apply)            
                % Log current method
                memrigui_log(app, ...
                    ['Executing method: ' methods_apply{mi}]);
    
                % Check for options.
                if isfield(opts.((vnames{mi})),'varname')
    
                    % Options name and value
                    copts_varname = {opts.(vnames{mi})(:).varname};
                    copts_value = {opts.(vnames{mi})(:).val};

                    % Convert opts to cell-list
                    copts = cat(2, copts_varname', copts_value')';
                    copts = [copts(:)];

                    % Log method-options
                    app = processing_log(app, copts);

                    % Execute method callback with options
                    app.memri = feval(callbacks{mi}, app.memri, copts{:});

                else
                    % Execute method without options
                    app.memri = feval(callbacks{mi}, app.memri);
                end
        
                % Update gui log with log from method callback
                memrigui_log_listbox_update(app);
            end

        end

        function app = processing_log(app, copts)
        % Dedicated function to speed up display of log-options during 
        % processing loop. 
        %
        % copts = cell-list format of the options structure.
          
            % Proper log-format of method-options
            copts_log = cell(1,numel(copts)./2);
            for kk = 1:2:numel(copts)
                % Input variable value
                dval = copts{kk+1};
                % Convert to string
                if ~ischar(dval), dval = int2str(copts{kk+1});end
                copts_log{round(kk./2)} = [copts{kk} ': ' dval];
            end
            copts_log = strjoin(copts_log, ' | ');

            % LOG
            memrigui_log(app, 'Using options: ', copts_log);
                
        end

        function app = processing_post(app)
        % Check the processing settings in details tab "Processing" and
        % execute requested tasks. 
        % Includes: 1. Export data via overwrite, automatic or by custom 
        % output location. 2. Save used parameters preset to file. 3. Open
        % the file directory. 4. Send MeMRI data to viewer. 5. Shutdown the
        % MeMRI PTB App.
        
        % Disable busy light
        memrigui_lamp_toggle(app, 1);
        
        % LOG
        memrigui_log(app,'Initiating post-processing steps...');

        % Get the settings from tabgroup settings its tick-box node-tree
        % ---  < Do NOT loo over, save-data has priority >  --- %
        checkedNodes = ...
            app.TabGroup_Details.Children(1).Children(1).CheckedNodes;
                
        % // --- Save data: Overwrite or new-file       
        doAutomatic = sum(strcmp({checkedNodes(:).Text}, 'Automatic'));
        doOverwrite = sum(strcmp({checkedNodes(:).Text}, 'Overwrite'));
        doCustom = sum(strcmp({checkedNodes(:).Text}, 'Custom location'));

        % If any option selected - run save-fcn
        if any([doAutomatic doOverwrite doCustom])
            app = memrigui_saveData(app,'overwrite', doOverwrite, ...
                                        'userinput', doCustom);
        end                                 
        % < ------------------------------------------------------------ >


        % // --- Export preset: save the parameters preset used to file.
        doPreset = sum(strcmp({checkedNodes(:).Text}, 'Export Preset')); 
        if doPreset
            if isfield(app.memri, 'opts')
                memrigui_ParametersPreset_save(app, app.memri.opts);
            end
        end 


        % // --- Viewer
        doViewer = sum(strcmpi({checkedNodes(:).Text}, 'Open in Viewer'));
        if doViewer
            CSIgui(app.memri, 'memriptb');
        end


        % // --- Open directory
        doDir = sum(strcmpi({checkedNodes(:).Text}, 'Open directory'));
        if doDir
            if isfield(app.memri, 'filepath')
                fp = fileparts(app.memri.filepath);                
            elseif isfield(app, 'filepath')
                fp = fileparts(app.filepath);               
            end
            winopen(fp);
        end
        
        % Disable busy light
        memrigui_lamp_toggle(app, 0);

        % // --- Close GUI
        doClose = sum(strcmpi({checkedNodes(:).Text}, 'Shutdown'));
        if doClose
            UIFigure_MeMRI_PTBCloseRequest(app);
            return;
        end

        end % < end of post-processing >

        function app = memrigui_saveData(app, varargin)
        % Save the MeMRI data struct to file. If overwrite is enabled (1)
        % the original mat-file will be overwritten. Otherwise the it
        % will be exported with a new-string.
        %
        % memri_saveData(app, 'overwrite', 1); 
        % memri_saveData(app, 'userinput', 1);
        %
        % Overwrite has priority over userinput: avoid both input arg.!

        % Parse input
        opts.overwrite = 0; opts.userinput = 0;
        for vi = 1:2:numel(varargin)
            opts.(varargin{vi}) = varargin{vi+1};
        end

        % Convert input to cell-list
        opts = opts2list(opts);

        % --- Save data
        app.memri = memri_saveData(app.memri, opts{:});

        % Log update
        memrigui_log_listbox_update(app); % Add log to GUI if updated.

        end


        % \\ Tabgroup Details Callbacks ------------------------- %
        % ------------------------------------------------------- %

        function app = tabgroup_initialise(app)
            % Check the "Operations" list and add corresponding tabs
            % to the tab group that allow changing parameters and settings.                      

            % Always check for the data-tab
            if ~isfield(app.tabdata, 'Data')
                app = tabgroup_tab_data(app);
            end
 
            % Execute tab-initialisation for each method
            methods2add = app.ListBox_Methods_Apply.Items;
            emptyBool = sum(cellfun(@(x) isempty(x), methods2add));
            if ~emptyBool
                for kk = 1:numel(methods2add)
                    if ~isfield(app.tabdata, methods2add{kk})    
                        % Create tabdata for every method
                        tab_initialise(app, methods2add{kk});           
                    end
                end
            end

            % Immidiate update to flush cosmetics.
            tabgroup_sort(app);
        end
        
        function app = tabgroup_sort(app)
            % Update order of tabs - processing and data- tab first
            % followed by all methods in order of execution.

            % Methods list
            methods2apply = app.ListBox_Methods_Apply.Items;

            % Tab list
            tnames = {app.TabGroup_Details.Children(:).Title};

            % Find non-method tabs: Processing and Data
            ind_data = find(strcmp(tnames, 'Data'));
            ind_proc = find(strcmp(tnames, 'Processing'));

            % Sorting vector
            svec = cellfun(@(x) find(strcmp(x, tnames)), methods2apply,...
                'UniformOutput',false); svec = cell2mat(svec);
            svec = [ind_proc ind_data svec];

            % Sort
            if ~isequal(svec, 1:numel(svec))
                tmp = app.TabGroup_Details.Children(svec);
                app.TabGroup_Details.Children = tmp;                    
            end

            % Expand then UI tree in processing
            expand(app.NodeTree_Processing);
        end

        function app = tabgroup_remove_tab(app, tnames)
            % Remove tabs with method name (display!) defined in tnames.
            % NB! Does NOT remove methods from the methods listbox.

            % Remove tabs
            for kk = 1:numel(tnames)
                tname = genVarName(tnames{kk});
                if isfield(app.tabdata, tname)
                    % Delete gfx
                    delete(app.tabdata.(tname).tab); 
                    % Remove field
                    app.tabdata = rmfield(app.tabdata, tname); 
                end
            end

        end

        function app = tabgroup_tab_data(app)
        % Create the data-tab and display to user - this is custom and
        % different from the methods-tabs.
            
            % Only if data available.
            if ~isempty(app.memri) && isfield(app.memri, 'data')              
                
                % Data NFO details.
                flds = fieldnames(app.memri.nfo);
                vals = struct2cell(app.memri.nfo);

                % < Custom-for-data >
                % Fieldname correction via a matching library:
                lib = {...
                    'nucleus',           'Nucleus: '; ...
                    'fieldstrength',     'Fieldstrength (T):';...
                    'res',               'Resolution [X x Y x Z] (mm):';...
                    'TE',                'Echo-time, TE (s): ';...
                    'TR',                'Repetition time, TR (s): ';...
                    'fov',               'Field-of-View [X x Y x Z] (mm):';...                    
                    'bandwidth',         'Bandwidth (Hz): ';...
                    'offcenter',         'Volume Offcenter [X x Y x Z] (mm):';...
                    'orientation',       'Orientation:';...
                    'dwelltime',         'Dwelltime (s):';...
                    'transmit_frequency','Transmit frequency (Hz):';...
                    'dim',               'Matrix size: ';...
                    'domain',            'Data domain (0 = k-space, 1 = time, 2 = frequency):'...
                    };
                
                % Query input ui-elements
                query_style = repmat({'edit'}, 1, numel(flds));

                % Apply label-text library for data
                for kk = 1:numel(flds)
                    ind = strcmp(lib(:,1), flds{kk});
                    if sum(ind) > 0, flds(kk) = lib(ind,2); end
                end
                % </ Custom-for-data />
                
                % Tab-name
                tname = genVarName('Data');

                % Create tab for data-details
                app.tabdata.(tname).tab = uitab(app.TabGroup_Details,...
                    'Title', 'Data', ...
                    'ForegroundColor', app.clr.bg ,...
                    'BackgroundColor', app.clr.bg ); % = color of tab-title!
                                
                % Default tab-initialisation of user interface
                tab_initialise_ui(app, tname, query_style);
                             
                % Update UI query-elements in tab-object
                for kk = 1:numel(flds)
                    % Add label text
                    app.tabdata.(tname).label(kk).Text = [flds{kk}];

                    % Convert non-char to string
                    vv = vals{kk};
                    if ~ischar(vals{kk}), vv = values2str(vals{kk}); end

                    % Set inputobject value
                    app.tabdata.(tname).inputobj(kk).Value = vv;                    
                end

                % < Custom for data-tab >
                % Add matrix size and labels to nfo/title label.
                app.tabdata.(tname).tl.FontName = 'Consolas';
                app.tabdata.(tname).tl.HorizontalAlignment = 'center';
                app.tabdata.(tname).tl.VerticalAlignment = 'top';

                % Create text
                linp = sprintf('%8s', app.memri.labels{:});
                minp = sprintf('%8i', size(app.memri.data));                
                txt = sprintf('%s\n%s', linp(5:end), minp(5:end));
                
                % Apply text
                app.tabdata.(tname).tl.Text = txt;
                app.tabdata.(tname).tl.FontWeight = 'bold';
                % </ Custom for data-tab />

            end
        end
        
        function tab_initialise(app, method_name)
        % Create a new tab in the Tabgroup_Details for every method found
        % in the listbox_methods_apply.
        %
        % Settings need to be loaded here too.

            % // Generate the method its tab name //
            tname = genVarName(method_name);

            % Security check - check if tab is already a child.
            tabnames = cellfun(@genVarName,...
                    {app.TabGroup_Details.Children(:).Title},...
                    'UniformOutput', false);
            if isfield(app.tabdata, tname) || ismember(tname,tabnames)
                return; 
            end

            % Create tab for data-details
            app.tabdata.(tname).tab = uitab(app.TabGroup_Details,...
                'Title', method_name, ...
                'ForegroundColor', app.clr.bg ,...
                'BackgroundColor', app.clr.bg ); % = color of tab-title!
            
            % Get options for method to use in tab-query input objects
            if isfield(app.gui_methods, tname) 
                
                opts = struct;
                if isfield(app.gui_methods.(tname), 'opts')
                    % get options for this method
                    opts = app.gui_methods.(tname).opts; 
                end

                % Default tab-initialisation of UI and return:
                %       app.tabdata.(tname).tab, .label, .inputobj, .tl
                tab_initialise_ui(app, tname, opts);   

                % Add all options to the tab-input and lable objects.
                tab_initialise_add_opts(app, tname, opts);

                % Add nfo from opts
                tab_initialise_add_nfo(app, tname);
            end

        end

        function tab_initialise_add_nfo(app, tname)

            % Add NFO to title label in tab if available
            if isfield(app.gui_methods.(tname), 'nfo')
                % Text
                app.tabdata.(tname).tl.Text = app.gui_methods.(tname).nfo;
            end
            
        end

        function tab_initialise_add_opts(app, tname, opts)
            % Add the settings to the each label input object in the tab
            % Opts contains the config details for this tname-method its
            % input variables i.e. format from config_methods.txt using
            % readMethods();
            
            % Only if queries available
            if ~isfield(opts, 'query'), return; end

            % See comments for opts-details.
            qry    = {opts(:).query};   % Label query
            dval   = {opts(:).display}; % Displayed values
            uitype = {opts(:).uistyle}; % input object style

            % Process display values
            dval = cellfun(@(x) strtrim(strsplit(x, ',')), dval,...
                'UniformOutput', false);

            % // Update UI query-elements in tab-object
            for kk = 1:numel(qry)
                % Add label text
                app.tabdata.(tname).label(kk).Text = [qry{kk}];

                %  Convert non-char to string if necessary - and
                %  maintain a string-format for non-integer decimals
                vv = dval{kk};
                switch uitype{kk}
                    case 'popup'  
                        % Set inputobject value
                        app.tabdata.(tname).inputobj(kk).Items = vv;                              

                    case 'edit'                   
                        % No cell input - only for popup
                        if iscell(vv), vv = vv{:}; end

                        % Set inputobject value
                        app.tabdata.(tname).inputobj(kk).Value = vv;         
                end      
            end

        end
       
        function tab_initialise_ui(app, tname, opts)
            % Initialise the default tab UI-elements according to #query 
            % elements required: query_types contains popup or edit for
            % every query!
            %
            % Uses: , tab_initialise_uigrid(), tab_initialise_cosmetics() 
            % and tab_initialise_scrollbar()

            % Input object style via opts input (struct or q-types)
            if ~isstruct(opts), query_types = opts;
            else    
                query_types = {};
                if isfield(opts, 'uistyle')
                    query_types = {opts(:).uistyle}; 
                end
            end
            nquery = numel(query_types);

            % Calculate correct height of tab-area and create position
            % vectors for label and input objects
            tab_initialise_uigrid(app, tname, nquery);           
            
            % Load in tab grid position details.
            tgrid = app.tabdata.(tname).tgrid; % here read-only
            
            % Add title-label.
            app.tabdata.(tname).tl = uilabel(app.tabdata.(tname).tab,...
                'Visible', 'on', 'Enable','on', 'Text', '',... 
                'Position', tgrid.pos.title, 'BackgroundColor', app.clr.bg,...
                'FontColor', app.clr.fg);

            % Add the objects to the tab-obj
            for qi = 1:nquery
                % Add label & set position
                app.tabdata.(tname).label(qi) = uilabel(...
                    app.tabdata.(tname).tab, ...
                    'Position', tgrid.pos.label(qi,:),...
                    'BackgroundColor', app.clr.bg,...
                    'Text', '...initialising...');
                
                % Add inputobj & set position
                switch query_types{qi}
                    case 'popup'
                    app.tabdata.(tname).inputobj(qi) = uidropdown(...
                        app.tabdata.(tname).tab, ...
                        'Position', tgrid.pos.inputobj(qi,:));
                                                       
                    case 'edit'
                    app.tabdata.(tname).inputobj(qi) = uieditfield(...
                        app.tabdata.(tname).tab, ...
                        'Position', tgrid.pos.inputobj(qi,:));                                      
                end
            end

            % Initialise scrollbar.
            tab_initialise_scrollbar(app, tname)            

            % Cosmetics of all object
            tab_initialise_cosmetics(app, tname)
            
        end
           
        function tab_initialise_uigrid(app, tname, nquery)
            % The tab settings layout is calculated here and includes: 
            %   required tab-height, position vectors for query-elements.
            % A single query-element consist out of a label and input 
            % object. 
            
            % Will contain all details for this tab!
            tgrid = struct; 

            % Get tab object using tname
            tabobj = app.tabdata.(tname).tab;
            
            % Tab-width
            tabw = tabobj.Position(3) - 4; % Minus tab-border
            
            % These are default pixel heights of a edit i.e. inputobject 
            % and label obj
            sz_inputobj = 23; sz_label = 20; sz_title = 3*sz_label;
            
            % Distances between..
            dy = 6;     % query elements            
            bdx = 20;   % left/right tab-border
            bdy = 20;   % top/down tab-border

            % Size of single query-element: edit and label + offset
            sz_combined = sz_inputobj + sz_label + dy;
                        
            % Required tab-height: 
            % combined-size of query-element x query + offset to borders +
            % title-label
            tgrid.height = (sz_combined * nquery) + (sz_title + dy) + (2*bdy) - dy ;
            
            % Dependent on required tab-size - calculate position vectors
            % for all objects
            th_real = tgrid.height;
            if tgrid.height < tabobj.Position(4)
                th_real = tabobj.Position(4); % Real tab-height
            end

            % Height limit used to calculate position of title-label and
            % input-elements.
            pvec_lim = th_real - (sz_title + bdy + dy) ;
        
            % Position vectors for label and input-objects: top to bottom.
            pvec_label = pvec_lim:-sz_combined:(bdy+sz_inputobj);
            pvec_edit = pvec_label - sz_label - (0.75*dy);            

            % Define tab-width
            scrollbar_pixels = 0; % 17pixels is a safety for a scrollbar
            if tgrid.height > tabobj.Position(4), scrollbar_pixels = 17;end
            twidth = tabw -(bdx*2) - scrollbar_pixels; 

            % Create pos vec for title label
            title_ypos = th_real - bdy - sz_title;
            tgrid.pos.title = [bdx title_ypos twidth sz_title];  

            % Create pos vec for inputobj
            tgrid.pos.inputobj = cell2mat(arrayfun(@(yy)...
                [bdx, yy, twidth, sz_inputobj], pvec_edit,...
                'UniformOutput', false)');

            % Create pos vec for labels
            tgrid.pos.label = cell2mat(arrayfun(@(yy) ...
                [bdx, yy, twidth, sz_label], pvec_label,...
                'UniformOutput', false)');
                 
            % // --- Store all size-details in output struct.
            tgrid.sz.inputobj = sz_inputobj; tgrid.sz.label = sz_label;
            tgrid.sz.title = sz_title; tgrid.sz.combined = sz_combined; 
            tgrid.sz.tabw = tabw; tgrid.sz.twidth = twidth;
            tgrid.sz.bdy = bdy; tgrid.sz.bdx = bdx; tgrid.sz.dy = dy;

            % Store tab grid details
            app.tabdata.(tname).tgrid = tgrid;
        end
    
        function tab_initialise_scrollbar(app, tname)
            % Enable scrolling if required tab-height > tabgroup-height
            % FORCE height by invisible obj or something?
            
            % Get tab object using tname
            tabobj = app.tabdata.(tname).tab; 
            % Tab grid details (read-only)
            tgrid = app.tabdata.(tname).tgrid; 
    
            if tgrid.height > tabobj.Position(4)
                % Make tab-scrollable
                tabobj.Scrollable = 'on';
                
                % < Trick >
                % Add an extra label to force correct virtual tab-height
                % i.e. the offset from top to first uiobj (title label).
                pos = [tgrid.sz.bdx tgrid.height-tgrid.sz.bdy ...           % x and y
                       tgrid.sz.twidth tgrid.sz.bdy];                       % w and h
                app.tabdata.(tname).trick_label = uilabel(tabobj,...
                    'Visible', 'on', 'Enable','on', 'Text', '',... 
                    'Position', pos, 'BackgroundColor', app.clr.bg,...
                    'FontColor', app.clr.bg);
            end
        end

        function tab_initialise_cosmetics(app, tname)
            % Title label
            
            % Minor cosmetics.
            app.tabdata.(tname).tl.FontName = 'Consolas';
            app.tabdata.(tname).tl.HorizontalAlignment = 'left';
            app.tabdata.(tname).tl.VerticalAlignment = 'top';
            app.tabdata.(tname).tl.WordWrap = 1;

            % Only if label and input objects are avaible - continue with
            % cosmetics.
            if ~isfield(app.tabdata.(tname), 'label'), return; end

            % Label objects available - set cosmetics.
            for kk = 1:numel(app.tabdata.(tname).label)
                app.tabdata.(tname).label(kk).BackgroundColor = app.clr.bg;
                app.tabdata.(tname).label(kk).FontColor = app.clr.fg;
                app.tabdata.(tname).label(kk).FontWeight = 'bold';
            end

            % Set all input object colors 
            for kk = 1:numel(app.tabdata.(tname).inputobj)
                app.tabdata.(tname).inputobj(kk).BackgroundColor = app.clr.bg;
                app.tabdata.(tname).inputobj(kk).FontColor = app.clr.fg;
            end
        end


        % \\ Options and Parameter Functions -------------------- %
        % ------------------------------------------------------- %  

        % // --- Convert details in methods-tabs to options struct.
        function opts = tab2opts(app)
        % Returns structure opts with a field for every method found in 
        % the tabgroup. 
        % 
        % The method field is of size 1xN options required for the callback
        % of said method. The user input is parsed according details in
        % config_methods.txt as loaded into app.gui_methods.
        %
        % Opts format for methods with input-arguments:
        %      opts.(method_name)(1:#options)
        %               .varname    parameters name for the callback
        %               .val        parameter value        
        % Opts format for methods without input-arguments:
        %       opts.(method_name) = []
                            
        % Set output struct;
        opts = struct;

        flds = fieldnames(app.tabdata);  % Tab names in database
        flds(strcmp(flds, 'Data')) = []; % Remove data-tab entry
        for ti = 1:numel(flds) % Methods loop
            tname = flds{ti};               % Method tab name            
            tdata = app.tabdata.(tname);    % Method tab data           
            mnfo = app.gui_methods.(tname); % Method database nfo

            % Check for inputobj i.e. opts
            if isfield(tdata, 'inputobj')  
                for pp = 1:numel(tdata.inputobj) % Options-loop

                    % Get user-input in input-object.
                    val = tdata.inputobj(pp).Value;         
                    
                    % If dropdown/popup, check for items
                    if isprop(tdata.inputobj(pp), 'Items') 
                        
                        % Selected index in popup-list (logical)
                        indx = strcmp(tdata.inputobj(pp).Items, val); 

                        % Use mnfo.input and .type to parse input-data         
                        cnfg_inp = strsplit(mnfo.opts(pp).input, ',');
                        cnfg_inp = opts_parse(mnfo.opts(pp), cnfg_inp);

                        % Value for parameter in correct data-format
                        val = cnfg_inp(indx);

                    else % Editfield string 
                        % Parse according to input type required for 
                        % method-function.
                        val = opts_parse(mnfo.opts(pp), val);              
                    end
                    
                    % Store opts parameters
                    opts.(tname)(pp).varname = mnfo.opts(pp).varname;
                    opts.(tname)(pp).val = val;

                end % End options-loop
            else
                opts.(tname) = []; % No input options to set.
            end
            
        end % End methods-loop

        end
        
        % // --- Set options-struct details in methods-tabs.
        function app = opts2tab(app, opts)
        % Apply an opts struct to tab-group settings
        % Without opts-argument will revert to the default parameters in 
        % "\Preset\config_methods.txt".

        % Default options
        if nargin == 1, opts = config2opts(); end

        % Current methods and methods nfo from config
        methods_apply = app.ListBox_Methods_Apply.Items;
        mnfo = app.gui_methods; 

        % Set 
        for mi = 1:numel(methods_apply)
            tname = genVarName(methods_apply{mi});
            

            % Method found in tabgroup data, opts-struct and config
            if isfield(app.tabdata, tname) && ...
                    isfield(opts, tname) && isfield(mnfo, tname) && ...
                    numel(opts.(tname)) > 1 % Options are available
  
                % Get all methods its input options & variable names.
                config_opts_names = {mnfo.(tname).opts(:).varname};
                config_opts = mnfo.(tname).opts;                

                % Loop each inputobj i.e. option
                for pp = 1:numel(opts.(tname))
                    nval = opts.(tname)(pp).val; % New value
                    vname = opts.(tname)(pp).varname; % Value name

                    % Find name in methods-config-nfo: config opts index
                    copts_ind = strcmp(config_opts_names, vname);
                    
                    switch config_opts(copts_ind).uistyle 
                        case 'popup'
                            % Check what input value index corresponds with
                            % the new value
                            items = strsplit(config_opts(copts_ind).input, ',');
                            items = cellfun(@(x) opts_parse(config_opts(copts_ind), x),...
                                items, 'UniformOutput', false);
                            ind_nval_in_items = cellfun(@(x) isequal(nval, x), items);
                            
                            % Set value
                            app.tabdata.(tname).inputobj(copts_ind).Value = ...
                                app.tabdata.(tname).inputobj(copts_ind).Items(ind_nval_in_items);

                        case 'edit'

                            % Convert opts value to string
                            if iscell(nval), nval = nval{:}; end
                            if ~ischar(nval), nval = num2str(nval); end

                            % Set value
                            app.tabdata.(tname).inputobj(copts_ind).Value = nval;
                            
                    end                              
                end % options loop
            end % if-safety opts available
        end % method loop
        

        end

        % // --- Save current options for selected methods to mat-file
        function memrigui_ParametersPreset_save(app, opts)

            % Get current options if opts no input-arg
            if nargin == 2, opts = tab2opts(app); end

            % Filepath with instance#
            [fp, fn, ext] = fileparts(app.memri.filepath);
            if isfield(app.memri, 'instance')
                instance = app.memri.instance;
            else
                instance = string(datetime('now','Format','yyyy.MM.dd.HHmmss'));
            end
            fpn = strcat(fp, '\', fn, instance,'.ParameterPreset', ext);

            % Store opts-struct to file
            save(fpn, "opts");

            % LOG
            memrigui_log(app, 'Saved preset to file:', fpn);

        end

        function memrigui_ParametersPreset_load(app)
            app.memri
        end

        % \\ LOG Functions -------------------------------------- %
        % ------------------------------------------------------- %

        function app = memrigui_log(app, varargin)     
        % Writes any strings and most integer lists to log-data. If
        % memri-data is loaded it will write to log-file and display in
        % GUI.

            % Check if data-str is loaded and full app-data available.
            if ~isempty(app.memri) && isfield(app.memri, 'data')
                % Set log-data in memri-struct;
                app.memri = memri_log(app.memri, varargin{:});
    
                % Update listbox log display
                memrigui_log_listbox_update(app);  
            else
                
                % First time log-generation: LOG User + GUI; there is no 
                % appdata struct available.
                if ~isfield(app.memri,'log')
                    app.memri.log = app.ListBox_Log.Items;    
                    % Create log with general MeMRI PTB log-function
                    %  \* = Do  Not Save To File
                    app.memri = memri_log(app.memri, '\*', ...
                     'GUI-Log synced with MeMRI-PTB format.', ...
                     'Log-file will be generated when data is available.');   
                end
                
                % Append log
                app.memri = memri_log(app.memri, '\*', varargin{:});
                % app.memri.log = cat(1,app.memri.log(:), varargin(:));
    
                % Update listbox log display
                memrigui_log_listbox_update(app);
                
            end            
            % Update graphics otherwise MATLAB can execute all functions
            % first before showing log-updates in listbox object.
            drawnow;

        end
       
        function memrigui_log_listbox_update(app)
            % Set log from memri-struct in log-listbox of gui.
            if ~isequal(app.ListBox_Log.Items, app.memri.log)
                app.ListBox_Log.Items = flip(app.memri.log, 1);
                app.ListBox_Log.ValueIndex = 1;
                drawnow;
            end                
        end

        % \\ File and Data handling ----------------------------- %
        % ------------------------------------------------------- %
        
        % - Executed to select, load and parse a file.
        function memrigui_file_open(app, doSelect)
        % Select a file via ui, read the data and parse the content.
        % Executed by mb: Main > Open...
            
            % Use selection UI if arg is missing
            if nargin < 2, doSelect = 1; end
            % Select file and log: set app.filepath
            if doSelect
                idx = memrigui_file_select(app); 
                if idx == 0, return; end
            end

            % Busy state
            memrigui_lamp_toggle(app, 1); 

            % // ---Read mat-file
            memrigui_file_read_mat(app);
            
            % // --- Parse memri-struct
            memrigui_parse_memri(app);                        
                    
            % Show busy state
            memrigui_lamp_toggle(app, 0);

            % // --- Initialise tab for data!
            tabgroup_initialise(app);


        end
           
        % - Executed by memgui_file_open()
        function [idx, fn, fp] = memrigui_file_select(app)
        % Opens file-select UI and set filepath in app.filepath. 
        % idx equals 0 if user canceled input.

            % Select file(s) to load
            [fn, fp, idx] = uigetfile({'*.mat; mat-file'}, ...
                'MeMRI PTB GUI: Select MeMRI data.'); 
            if idx == 0, return; end
            app.filepath = [fp fn];
            
        end

        % - Executed by memgui_file_open()
        function memrigui_file_read_mat(app)            
            % Load mat-file into app.memri.         
            if ~isempty(app.filepath)
          
                % Check file-existance
                if exist(app.filepath, 'file') ~= 2
                    memrigui_log(app, 'Filepath does not exist.\n'); 
                    return;
                else
                    app.memri.input = nan;
                end

                % Log
                [fp,fn,ext] = fileparts(app.filepath);
                memrigui_log(app, ['File: ' fn ext], ['Path: ' fp], ...
                        'Loading data...');

                % Load mat-file
                app.memri.input = load(app.filepath); 
            end
        end
        
        % - Executed by memgui_file_open()
        function memrigui_parse_memri(app)
            % Check if loaded mat-file struct in app.memri.(XXX) is equal
            % "memri" and load into GUI memory properly. Start logging and
            % set GUI.
            
            % Store loaded memri-struct
            if ~isempty(app.memri) && isfield(app.memri, 'input')

                % Save log of GUI
                doLog = 0;
                if isfield(app.memri, 'log') && isfield(app.memri, 'instance')                    
                    log = app.memri.log; instance = app.memri.instance;
                    doLog = 1;
                end
                
                % // Store MeMRI-struct data only
                fld = fieldnames(app.memri.input);
                app.memri = app.memri.input.(fld{:}); % Set appdata.   

                % Restore log and set the new instance (related to
                % MeMRI-PTB GUI)
                if doLog 
                    if isfield(app.memri, 'log')
                        app.memri.log = cat(1, app.memri.log(:), log(:));
                    else
                        app.memri.log = log; % GUI log only.
                    end
                    app.memri.instance = instance;
                end

                % Update filepath field
                % Filepath in memri-struct can be a different host - if
                % path is not found on current system, use loaded mat-file
                % as location.
                [fp, fn, ext] = fileparts(app.memri.filepath);
                if exist(fp, 'dir') ~= 7    
                    fp = fileparts(app.filepath);
                    app.memri.filepath = [fp '\' fn ext];
                end            

                % LOG to log-file
                memrigui_log(app, 'MeMRI PTB data-struct loaded and parsed.');  
                                 
                % Update GUI
                [~, fn , ext] = fileparts(app.memri.filepath);
                app.Edit_File.Value = [fn ext];
            end
        end
        
        % - Executed by startupFcn()
        function memrigui_parse_input(app, varargin)
        % Parse userinput from GUI startup    

            % Only a single input is expected:
            % struct || filepath
            if isscalar(varargin)
                % Show busy state
                memrigui_lamp_toggle(app, 1);

                % Parse it as struct or filepath for app.memri
                if isstruct(varargin{1})        % MeMRI structure
                    memrigui_log(app, 'Parsing input-structure...');

                    % Set input in memri-struct
                    app.memri.input.memri = varargin{1};
                                     
                    % Initialise tab for data!
                    tabgroup_initialise(app);

                elseif ischar(varargin{1})      % Filepath to mat-file
                    memrigui_log(app, 'Parsing input filepath...');

                    % Set filepath
                    app.filepath = varargin{1};
                    
                    % Load mat-file
                    memrigui_file_read_mat(app);
                    if ~isstruct(app.memri.input)
                        memrigui_lamp_toggle(app, 0); return;
                    end
                else
                    % Log
                    memrigui_log(app, ...
                    ['Input arguments are not compatibile with MeMRI PTB. ' ... 
                    'See the Quick-Start Guide in the help menu.']);
                    memrigui_lamp_toggle(app, 0); return;
                end

                % Parse memri-struct
                memrigui_parse_memri(app);  

                % Initialise tab for data!
                tabgroup_initialise(app);

                % Show busy state
                memrigui_lamp_toggle(app, 0);
            end
        end


        function memrigui_ListBox_Methods_ButtonFnc(app, event)            
            % If user pressed delte - remove selection using the 
            % delete-button callback.
            Button_Method_RemovePushed(app, event);
        end

    end
    
    methods (Access = public)
        
        function updateInput(app, varargin)

            % Reset the app
            memrigui_reset(app);

            % Parse input
            if numel(varargin) > 1
                memrigui_log(app, ['Multiple input arguments detected ' ...
                                ' - unsupported. Aborted parsing input.']);
                return;
            end
            memrigui_parse_input(app, varargin{1});
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
                        
            % Load available methods from config-file
            methods_initialise(app);
                           
            % Parse userinput - even if none :)
            memrigui_parse_input(app, varargin{:});

        end

        % Button pushed function: Button_Method_Add
        function Button_Method_AddPushed(app, event)
            
            %% Add Item

            % Remove empty lines in listbox
            ind_empty = cellfun(@(x) isempty(x), ...
                app.ListBox_Methods_Apply.Items);
            
            % Current list of methods to apply
            methods_apply = app.ListBox_Methods_Apply.Items(~ind_empty);          
            
            % Items to add
            methods_add = app.ListBox_Methods_List.Value;

            % Filter duplicates
            ind_dupes = cellfun(@(x) strcmp(x, methods_add),...
                methods_apply, 'UniformOutput', false);  
            ind_dupes = sum(cell2mat(ind_dupes'),1);
            methods_add(logical(ind_dupes)) =  [];

            % Add entries to lb
            if ~isempty(methods_add)
                app.ListBox_Methods_Apply.Items = cat(2,methods_apply, ...
                    methods_add);
            end

            %% Update Parameters tab window
            tabgroup_initialise(app);

        end

        % Button pushed function: Button_Method_Remove
        function Button_Method_RemovePushed(app, event)
            
            % Get list of items to delete from listbox
            del = app.ListBox_Methods_Apply.Value;
            ind_del = cellfun(@(x) strcmp(app.ListBox_Methods_Apply.Items,x),...
                del, 'UniformOutput',false);
            ind_del = sum(cell2mat(ind_del'),1);
            app.ListBox_Methods_Apply.Items(logical(ind_del)) = [];

            % Remove methods from tabgroup.
            tabgroup_remove_tab(app, del);

        end

        % Button pushed function: Button_ListBox_Methods_Up
        function Button_ListBox_Methods_UpPushed(app, event)
            % Move selected listbox items up. Supports multi-select.

            % lbox data
            mlist = app.ListBox_Methods_Apply.Items;
            mselected = app.ListBox_Methods_Apply.Value;
            
            % Index(es) of selected item(s)
            ind = zeros(1,numel(mselected));
            for si = 1:numel(mselected)
                tmp = cell2mat(cellfun(@(x) strcmp(x, mselected(si)), mlist,...
                           'UniformOutput', false)); 
                ind(si) = find(tmp == 1);
            end
            ind = sort(ind); % Ensure sel. items from top to bottom.
           
            % Move-up ceiling safety
            if ind(1) == 1
                for ii = 1:numel(ind)
                    if numel(ind) > ii && ind(ii+1) == ii+1
                        ind(ii+1) = 1;
                    end
                end
            end
            
            % Move item(s) up iteratively
            if ~isequal(ind, 1:numel(mselected))
                for si = 1:numel(ind)
                    if ind(si) ~= 1
                        mlist([ind(si)-1 ind(si)]) = mlist([ind(si) ind(si)-1]); 
                        app.ListBox_Methods_Apply.Items = mlist;                 
                    end
                end
            end

            % Update tabgroup settings
            tabgroup_sort(app);

        end

        % Button pushed function: Button_ListBox_Methods_Down
        function Button_ListBox_Methods_DownPushed(app, event)
            % Move selected listbox items up. Supports multi-select.

            % lbox data
            mlist = app.ListBox_Methods_Apply.Items;
            mselected = app.ListBox_Methods_Apply.Value;
            
            % Index(es) of selected item(s)
            ind = zeros(1,numel(mselected));
            for si = 1:numel(mselected)
                tmp = cell2mat(cellfun(@(x) strcmp(x, mselected(si)), mlist,...
                           'UniformOutput', false)); 
                ind(si) = find(tmp == 1);
            end
            % Ensure sel. items from bottom to top
            ind = sort(ind, 'descend'); 
           
            % Move-down ceiling safety
            if ind(1) == numel(mlist)
                for ii = 1:numel(ind)
                    if numel(ind) > ii && ind(ii+1) == numel(mlist)-ii
                        ind(ii+1) = numel(mlist);
                    end
                end
            end
            
            % Move item(s) up iteratively
            if ~isequal(ind, 1:numel(mselected))
                for si = 1:numel(ind)
                    if ind(si) ~= numel(mlist)
                        mlist([ind(si) ind(si)+1]) = mlist([ind(si)+1 ind(si)]); 
                        app.ListBox_Methods_Apply.Items = mlist;                 
                    end
                end
            end

            % Update tabgroup settings
            tabgroup_sort(app);

        end

        % Button pushed function: Button_File
        function Button_FilePushed(app, event)
            memrigui_reset(app);
            memrigui_file_open(app);
        end

        % Button pushed function: Button_File_Reload
        function Button_File_ReloadPushed(app, event)
            if ~isempty(app.filepath)
                % Remember filepath to file
                fpn = app.filepath;

                % Reset GUI
                memrigui_reset(app);

                % Reload file
                app.filepath = fpn; memrigui_file_open(app, 0);                               
            else
                memrigui_log(app,'Reload aborted.',...
                    '! No file location in memory !');
            end
        end

        % Button pushed function: Button_View_Data
        function Button_View_DataPushed(app, event)
            CSIgui(app.memri, 'memriptb');
        end

        % Button pushed function: Button_Processing
        function Button_ProcessingPushed(app, event)
            % Read the current list of operations and start executing each
            % in order of the list.
            
            processing_initiate(app);

        end

        % Button pushed function: Button_View_Parameters
        function Button_View_ParametersPushed(app, event)
            assignin("base","app", app);
        end

        % Selection change function: TabGroup_Details
        function TabGroup_DetailsSelectionChanged(app, event)
     
            tstr = genVarName(app.TabGroup_Details.SelectedTab.Title);
            if isfield(app.tabdata, tstr) && ...
                    isfield(app.tabdata.(tstr), 'inputobj')
                focus(app.tabdata.(tstr).inputobj(1)); 
            end
        end

        % Button pushed function: Button_ParametersPreset_Default
        function Button_ParametersPreset_DefaultPushed(app, event)
            memrigui_lamp_toggle(app, 1);
            
            % Sets the default input parameters from config_methods.txt
            opts2tab(app);

            memrigui_lamp_toggle(app, 0);
        end

        % Button pushed function: Button_ParametersPreset_Export
        function Button_ParametersPreset_ExportPushed(app, event)
            % Enable busy-light
            memrigui_lamp_toggle(app, 1);

            % Get options from tabgroup settings
            opts = tab2opts(app);

            % Current active directoy
            def = '\Presets\ParametersPreset'; 
            cdir = pwd; cdir = strsplit(cdir, '\');
            if strcmp(cdir{end}, 'Metabolic-MRI-Processing-Toolbox')
                def = [pwd '\Presets\ParametersPreset'];
            end

            % Location to store file           
            [fn, fp, idx] = uiputfile({'*.mat;';'MATLAB file'},...
                'Export method parameters to file...', def);
            if ~idx, memrigui_lamp_toggle(app, 0); return; end

            % Save opts to file 
            save([fp fn], 'opts');
            
            % Disable busy-light
            memrigui_lamp_toggle(app, 0);
        end

        % Button pushed function: Button_ParametersPreset_Import
        function Button_ParametersPreset_ImportPushed(app, event)
            % Enable busy-light
            memrigui_lamp_toggle(app, 1);

            % Current active directoy
            def = '\Presets\'; cdir = pwd; cdir = strsplit(cdir, '\');
            if strcmp(cdir{end}, 'Metabolic-MRI-Processing-Toolbox')
                def = [pwd '\Presets\'];
            end               

            % Location of file      
            [fn, fp, idx] = uigetfile({'*.mat;';'MATLAB file'},...
                'Import method parameters from file...', def);
            if ~idx, memrigui_lamp_toggle(app, 0); return; end

            % Save opts to file 
            load([fp fn], 'opts');

            % Apply opts to method-tabgroup-settings
            opts2tab(app, opts);            

            % Disable busy-light
            memrigui_lamp_toggle(app, 0);            
        end

        % Button pushed function: Button_MethodsPreset_Default
        function Button_MethodsPreset_DefaultPushed(app, event)
            
            % Get root
            memri_root = memri_findRoot();
            % Read the default methods preset.
            fpn = [memri_root 'Presets\Methods_Preset_Default.txt'];

            % Check for default-file existance
            if ~exist(fpn, 'file')                
                memrigui_log(app, ...
                    'Could not find default methods preset text-file:',...
                    fpn);
                return;
            end

            % // --- Load default methods file
            moi = readText(fpn);
            
            % Current items and tabs-names in GUI
            coi = app.ListBox_Methods_Apply.Items;
                       
            % Remove tab-data not included in default method-preset.
            ind = cell2mat(cellfun(@(x) contains(moi, x), coi, ...
                'UniformOutput', false)); del = coi(~sum(ind,1));
            tabgroup_remove_tab(app, del);

            % Update methods listbox
            app.ListBox_Methods_Apply.Items = moi;

            % Update settings tabgroup 
            tabgroup_initialise(app);

        end

        % Button pushed function: Button_MethodsPreset_Import
        function Button_MethodsPreset_ImportPushed(app, event)
            
            % // --- User want to load a methods preset text file
            
            % Enable busy light
            memrigui_lamp_toggle(app, 1);   

            % Get filepath to start fileselect UI at.
            fp = pwd;
            if isfield(app.memri, 'filepath'), fp = app.memri.filepath; end          

            % Get file
            [fn, fp, idx] = uigetfile({'*txt','Text-file'},...
                'Select methods preset text-file...', fp);
            if ~idx, memrigui_lamp_toggle(app, 0); return; end

            % Load file: methods of interest
            moi = readText([fp fn]);

            % Current items and tabs-names in GUI
            coi = app.ListBox_Methods_Apply.Items;
                       
            % Remove tab-data not included in default method-preset.
            ind = strcmpn(coi, moi);ind = cellfun(@(x) isempty(x), ind);
            del = moi(ind); tabgroup_remove_tab(app, del);

            % Set methods
            app.ListBox_Methods_Apply.Items = moi;

            % Update GUI
            tabgroup_initialise(app);

            % Disable busy light
            memrigui_lamp_toggle(app, 0);   
        end

        % Button pushed function: Button_MethodsPreset_Export
        function Button_MethodsPreset_ExportPushed(app, event)
            
            % Methods of interest
            moi = app.ListBox_Methods_Apply.Items;
            
            % Get filepath to start UI at.
            fp = pwd;
            if isfield(app.memri, 'filepath'), fp = app.memri.filepath; end            

            % Get file UI
            [fn, fp, idx] = uiputfile({'*.txt;','Text File'},...
                'Export methods preset..', fp);
            if idx == -1, return; end

            % Save to file
            status = writeText([fp fn], moi);
            if isnan(status)
                memri_log(app.memri, 'Failed saving methods-preset.');
                return;
            end
                
           
        end

        % Value changed function: Edit_File
        function Edit_FileValueChanged(app, event)
            
            value = strrep(app.Edit_File.Value, '"', '');
            if isfile(value)
                % Reset GUI
                memrigui_reset(app);

                [~, ~, ext] = fileparts(value);
                if strcmp(ext,'.mat')
                    app.filepath = value; memrigui_file_open(app, 0);
                end
            end                                  
        end

        % Callback function: NodeTree_Processing
        function NodeTree_ProcessingChanged(app, event)
            % Checks the node-tree and selected components:
            % Forces correct ticks of automatic, custom and overwrite
            % save-options i.e. only 1 can be enabled.

            % If none selected abort.
            if isempty(app.NodeTree_Processing.CheckedNodes)
                return; 
            end
            
            % Before and after user-interaction node-states
            if ~isempty(event.PreviousCheckedNodes)                
                nodeOld = {event.PreviousCheckedNodes(:).Text};
            else 
                nodeOld = cell(0,1);
            end
            nodeNew = {app.NodeTree_Processing.CheckedNodes(:).Text};
  
            % Labels of the Export-Data node its children of interest
            loi = {'Automatic', 'Custom location','Overwrite'};                   

            % Indexes of "labels of interest" in CheckedNodes
            indOld = cell2mat(strcmpn(nodeOld, loi));            
            indNew = cell2mat(strcmpn(nodeNew, loi));

            if isempty(indNew)
                return; % None of the nodes in "labels" are selected.

            elseif numel(indNew) == 3                 
                % Blocks selection of all sub-nodes in Export Data 
                % simultaneously.
                
                % Nodes
                nodes = app.NodeTree_Processing.CheckedNodes;
                
                % Remove all selected nodes including main Export Data
                nrange = 2:3; if isscalar(indOld), nrange = 1:3; end
                nodes([indNew(1)-1 indNew(nrange)]) = [];   

                % Remove Export-Data node if it is present
                % WILL enable all child-nodes if it is in CheckedNodes.
                ind_ed = strcmp({nodes(:).Text}, 'Export Data');
                nodes(ind_ed) = [];

                if isempty(nodes), nodes = []; end

                % Set new CheckedNodes.
                app.NodeTree_Processing.CheckedNodes = nodes;      
            
            elseif numel(indNew) == 2

                % Nothing to compare with.
                if isempty(indOld), return; end

                % Index of old checked loi in variable nodeNew
                indRemove = strcmp(nodeOld(indOld), nodeNew);
       
                % Get nodes
                nodes = app.NodeTree_Processing.CheckedNodes;

                % Remove the previously checked node
                nodes(indRemove) = [];
                % Set new checked nodes
                app.NodeTree_Processing.CheckedNodes = nodes;                

            end      

        end

        % Menu selected function: mb_main_open
        function mb_main_openSelected(app, event)
            memrigui_reset(app);
            memrigui_file_open(app);
        end

        % Menu selected function: mb_main_converter
        function mb_main_converterSelected(app, event)
            memri_DataConverter_GUI();
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            web('https://www.ihi-illuminate.org/');
        end

        % Menu selected function: UpdatesMenu
        function UpdatesMenuSelected(app, event)
            web('https://github.com/Illuminate-MeMRI/Metabolic-MRI-Processing-Toolbox');
        end

        % Menu selected function: TutorialMenu
        function TutorialMenuSelected(app, event)
            warndlg('T.B.D.');
        end

        % Menu selected function: QuickGuideMenu
        function QuickGuideMenuSelected(app, event)
            % <PDF at release>
            winopen('\Documents\MeMRI_Toolbox_Quick_Start_Guide.docx');
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            UIFigure_MeMRI_PTBCloseRequest(app, event);
        end

        % Close request function: UIFigure_MeMRI_PTB
        function UIFigure_MeMRI_PTBCloseRequest(app, event)
            delete(app);            
        end

        % Window key press function: UIFigure_MeMRI_PTB
        function UIFigure_MeMRI_PTBWindowKeyPress(app, event)
            if strcmp(event.Key, 'delete')
                memrigui_ListBox_Methods_ButtonFnc(app, event);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure_MeMRI_PTB = uifigure('Visible', 'off');
            app.UIFigure_MeMRI_PTB.Name = 'MeMRI Processing Toolbox';
            app.UIFigure_MeMRI_PTB.CloseRequestFcn = createCallbackFcn(app, @UIFigure_MeMRI_PTBCloseRequest, true);
            app.UIFigure_MeMRI_PTB.WindowKeyPressFcn = createCallbackFcn(app, @UIFigure_MeMRI_PTBWindowKeyPress, true);
            app.UIFigure_MeMRI_PTB.Tag = 'memriptbgui';
            app.mb_main = uimenu(app.UIFigure_MeMRI_PTB);
            app.mb_main.Text = 'Main';
            app.mb_main_open = uimenu(app.mb_main);
            app.mb_main_open.MenuSelectedFcn = createCallbackFcn(app, @mb_main_openSelected, true);
            app.mb_main_open.Tooltip = {'Open a mat-file.'};
            app.mb_main_open.Text = 'Open...';
            app.mb_main_converter = uimenu(app.mb_main);
            app.mb_main_converter.MenuSelectedFcn = createCallbackFcn(app, @mb_main_converterSelected, true);
            app.mb_main_converter.Text = 'Converter';
            app.mb_main_export = uimenu(app.mb_main);
            app.mb_main_export.Text = 'Export';
            app.mb_main_export_data = uimenu(app.mb_main_export);
            app.mb_main_export_data.Text = 'Data';
            app.mb_main_import = uimenu(app.mb_main);
            app.mb_main_import.Text = 'Import';
            app.mb_main_import_noise = uimenu(app.mb_main_import);
            app.mb_main_import_noise.Text = 'Noise Data';
            app.mb_main_import_images = uimenu(app.mb_main_import);
            app.mb_main_import_images.Text = 'MR Images';
            app.ExitMenu = uimenu(app.mb_main);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.mb_options = uimenu(app.UIFigure_MeMRI_PTB);
            app.mb_options.Text = 'Options';
            app.mb_options_methods = uimenu(app.mb_options);
            app.mb_options_methods.Text = 'Methods';
            app.mb_options_methods_import = uimenu(app.mb_options_methods);
            app.mb_options_methods_import.Text = 'Import';
            app.mb_options_methods_export = uimenu(app.mb_options_methods);
            app.mb_options_methods_export.Text = 'Export';
            app.mb_options_parameters = uimenu(app.mb_options);
            app.mb_options_parameters.Text = 'Parameters';
            app.mb_options_parameters_import = uimenu(app.mb_options_parameters);
            app.mb_options_parameters_import.Text = 'Import';
            app.mb_options_parameters_export = uimenu(app.mb_options_parameters);
            app.mb_options_parameters_export.Text = 'Export';
            app.mb_options_processing = uimenu(app.mb_options);
            app.mb_options_processing.Text = 'Processing';
            app.mb_options_processing_export_data = uimenu(app.mb_options_processing);
            app.mb_options_processing_export_data.Text = 'Export Data';
            app.mb_options_processing_export_data_automatic = uimenu(app.mb_options_processing_export_data);
            app.mb_options_processing_export_data_automatic.Text = 'Automatic';
            app.mb_options_processing_export_data_custom_location = uimenu(app.mb_options_processing_export_data);
            app.mb_options_processing_export_data_custom_location.Text = 'Custom location';
            app.mb_options_processing_export_data_overwrite = uimenu(app.mb_options_processing_export_data);
            app.mb_options_processing_export_data_overwrite.Text = 'Overwrite';
            app.mb_options_processing_export_preset = uimenu(app.mb_options_processing);
            app.mb_options_processing_export_preset.Text = 'Export Preset';
            app.mb_options_processing_after = uimenu(app.mb_options_processing);
            app.mb_options_processing_after.Separator = 'on';
            app.mb_options_processing_after.Text = 'After Processing';
            app.mb_options_processing_after_open_directory = uimenu(app.mb_options_processing_after);
            app.mb_options_processing_after_open_directory.Text = 'Open directory';
            app.mb_options_processing_after_open_in_viewer = uimenu(app.mb_options_processing_after);
            app.mb_options_processing_after_open_in_viewer.Text = 'Open in Viewer';
            app.mb_options_processing_after_shutdown = uimenu(app.mb_options_processing_after);
            app.mb_options_processing_after_shutdown.Text = 'Shutdown';
            app.mb_viewer = uimenu(app.UIFigure_MeMRI_PTB);
            app.mb_viewer.Text = 'Viewer';
            app.mb_viewer_csigui = uimenu(app.mb_viewer);
            app.mb_viewer_csigui.Text = 'CSIgui';
            app.mb_viewer_viewer = uimenu(app.mb_viewer);
            app.mb_viewer_viewer.Text = 'Viewer';
            app.mb_Help = uimenu(app.UIFigure_MeMRI_PTB);
            app.mb_Help.Text = 'Help';
            app.QuickGuideMenu = uimenu(app.mb_Help);
            app.QuickGuideMenu.MenuSelectedFcn = createCallbackFcn(app, @QuickGuideMenuSelected, true);
            app.QuickGuideMenu.Text = 'Quick Guide';
            app.TutorialMenu = uimenu(app.mb_Help);
            app.TutorialMenu.MenuSelectedFcn = createCallbackFcn(app, @TutorialMenuSelected, true);
            app.TutorialMenu.Text = 'Tutorial';
            app.UpdatesMenu = uimenu(app.mb_Help);
            app.UpdatesMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdatesMenuSelected, true);
            app.UpdatesMenu.Text = 'Updates...';
            app.AboutMenu = uimenu(app.mb_Help);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Separator = 'on';
            app.AboutMenu.Text = 'About';
            app.BusyLamp = uilamp(app.UIFigure_MeMRI_PTB);
            app.BusyLamp.Enable = 'off';
            app.ListBox_Methods_List = uilistbox(app.UIFigure_MeMRI_PTB);
            app.ListBox_Methods_List.Items = {''};
            app.ListBox_Methods_List.Multiselect = 'on';
            app.ListBox_Methods_List.Value = {''};
            app.ListBox_Methods_Apply = uilistbox(app.UIFigure_MeMRI_PTB);
            app.ListBox_Methods_Apply.Items = {''};
            app.ListBox_Methods_Apply.Multiselect = 'on';
            app.ListBox_Methods_Apply.Value = {''};
            app.ListBox_Log = uilistbox(app.UIFigure_MeMRI_PTB);
            app.ListBox_Log.Items = {'% --- Metabolic MRI Processing ToolBox GUI v0.5 --- %', ''};
            app.ListBox_Log.Value = '% --- Metabolic MRI Processing ToolBox GUI v0.5 --- %';
            app.Label_MethodsPreset = uilabel(app.UIFigure_MeMRI_PTB);
            app.Label_MethodsPreset.Text = 'Methods Preset:';
            app.Label_Details = uilabel(app.UIFigure_MeMRI_PTB);
            app.Label_Details.Tooltip = {'Data details, processing settings and selected methods parameters.'};
            app.Label_Details.Text = 'Details';
            app.Label_ParametersPreset = uilabel(app.UIFigure_MeMRI_PTB);
            app.Label_ParametersPreset.Text = 'Parameters Preset:';
            app.Label_Methods = uilabel(app.UIFigure_MeMRI_PTB);
            app.Label_Methods.Tooltip = {'Available methods to apply to MR spectroscopy data.'};
            app.Label_Methods.Text = 'Methods:';
            app.Label_Operations = uilabel(app.UIFigure_MeMRI_PTB);
            app.Label_Operations.Tooltip = {'Operations, i.e processing methods, that will be applied to the MR spectroscopy data.'};
            app.Label_Operations.Text = 'Operations:';
            app.Button_Processing = uibutton(app.UIFigure_MeMRI_PTB, 'push');
            app.Button_Processing.ButtonPushedFcn = createCallbackFcn(app, @Button_ProcessingPushed, true);
            app.Button_Processing.Text = 'Start';
            app.Button_ParametersPreset_Export = uibutton(app.UIFigure_MeMRI_PTB, 'push');
            app.Button_ParametersPreset_Export.ButtonPushedFcn = createCallbackFcn(app, @Button_ParametersPreset_ExportPushed, true);
            app.Button_ParametersPreset_Export.Tooltip = {'Import a mat-file with parameters for the selected methods.  (Parameters for unselected methods are ignored). '};
            app.Button_ParametersPreset_Export.Text = 'Export';
            app.Button_ParametersPreset_Import = uibutton(app.UIFigure_MeMRI_PTB, 'push');
            app.Button_ParametersPreset_Import.ButtonPushedFcn = createCallbackFcn(app, @Button_ParametersPreset_ImportPushed, true);
            app.Button_ParametersPreset_Import.Tooltip = {'Export the parameters set for all selected methods.'};
            app.Button_ParametersPreset_Import.Text = 'Import';
            app.Button_ParametersPreset_Default = uibutton(app.UIFigure_MeMRI_PTB, 'push');
            app.Button_ParametersPreset_Default.ButtonPushedFcn = createCallbackFcn(app, @Button_ParametersPreset_DefaultPushed, true);
            app.Button_ParametersPreset_Default.Tooltip = {'Import default parameters from the methods configuration file for the selected methods.'};
            app.Button_ParametersPreset_Default.Text = 'Default';
            app.Button_MethodsPreset_Export = uibutton(app.UIFigure_MeMRI_PTB, 'push');
            app.Button_MethodsPreset_Export.ButtonPushedFcn = createCallbackFcn(app, @Button_MethodsPreset_ExportPushed, true);
            app.Button_MethodsPreset_Export.Tooltip = {'Export selected methods to text-file.'};
            app.Button_MethodsPreset_Export.Text = 'Export';
            app.Button_MethodsPreset_Import = uibutton(app.UIFigure_MeMRI_PTB, 'push');
            app.Button_MethodsPreset_Import.ButtonPushedFcn = createCallbackFcn(app, @Button_MethodsPreset_ImportPushed, true);
            app.Button_MethodsPreset_Import.Tooltip = {'Import methods preset text-file.'};
            app.Button_MethodsPreset_Import.Text = 'Import';
            app.Button_MethodsPreset_Default = uibutton(app.UIFigure_MeMRI_PTB, 'push');
            app.Button_MethodsPreset_Default.ButtonPushedFcn = createCallbackFcn(app, @Button_MethodsPreset_DefaultPushed, true);
            app.Button_MethodsPreset_Default.Tooltip = {'Read in default list of methods to apply to CSI data.'};
            app.Button_MethodsPreset_Default.Text = 'Default';
            app.Button_View_Parameters = uibutton(app.UIFigure_MeMRI_PTB, 'push');
            app.Button_View_Parameters.ButtonPushedFcn = createCallbackFcn(app, @Button_View_ParametersPushed, true);
            app.Button_View_Parameters.Text = 'View';
            app.Button_Method_Add = uibutton(app.UIFigure_MeMRI_PTB, 'push');
            app.Button_Method_Add.ButtonPushedFcn = createCallbackFcn(app, @Button_Method_AddPushed, true);
            app.Button_Method_Add.Tooltip = {'Add method(s) as a processing step(s) to the "Operations" list.'};
            app.Button_Method_Add.Text = '→';
            app.Button_Method_Remove = uibutton(app.UIFigure_MeMRI_PTB, 'push');
            app.Button_Method_Remove.ButtonPushedFcn = createCallbackFcn(app, @Button_Method_RemovePushed, true);
            app.Button_Method_Remove.Tooltip = {'Remove selected processing method(s) in "Operations" list. '};
            app.Button_Method_Remove.Text = 'X';
            app.Button_View_Data = uibutton(app.UIFigure_MeMRI_PTB, 'push');
            app.Button_View_Data.ButtonPushedFcn = createCallbackFcn(app, @Button_View_DataPushed, true);
            app.Button_View_Data.Text = 'View';
            app.Button_ListBox_Methods_Up = uibutton(app.UIFigure_MeMRI_PTB, 'push');
            app.Button_ListBox_Methods_Up.ButtonPushedFcn = createCallbackFcn(app, @Button_ListBox_Methods_UpPushed, true);
            app.Button_ListBox_Methods_Up.Text = '↑';
            app.Button_ListBox_Methods_Down = uibutton(app.UIFigure_MeMRI_PTB, 'push');
            app.Button_ListBox_Methods_Down.ButtonPushedFcn = createCallbackFcn(app, @Button_ListBox_Methods_DownPushed, true);
            app.Button_ListBox_Methods_Down.Text = '↓';
            app.Button_File = uibutton(app.UIFigure_MeMRI_PTB, 'push');
            app.Button_File.ButtonPushedFcn = createCallbackFcn(app, @Button_FilePushed, true);
            app.Button_File.Tooltip = {'Open a mat-file.'};
            app.Button_File.Text = 'File:';
            app.TabGroup_Details = uitabgroup(app.UIFigure_MeMRI_PTB);
            app.TabGroup_Details.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_DetailsSelectionChanged, true);
            app.ProcessingTab = uitab(app.TabGroup_Details);
            app.ProcessingTab.Title = 'Processing';
            app.NodeTree_Processing = uitree(app.ProcessingTab, 'checkbox');
            app.ExportDataNode = uitreenode(app.NodeTree_Processing);
            app.ExportDataNode.Text = 'Export Data';
            app.AutomaticNode = uitreenode(app.ExportDataNode);
            app.AutomaticNode.Text = 'Automatic';
            app.CustomlocationNode = uitreenode(app.ExportDataNode);
            app.CustomlocationNode.Text = 'Custom location';
            app.OverwriteNode_2 = uitreenode(app.ExportDataNode);
            app.OverwriteNode_2.Text = 'Overwrite';
            app.ExportPresetNode = uitreenode(app.NodeTree_Processing);
            app.ExportPresetNode.Text = 'Export Preset';
            app.AfterprocessingNode = uitreenode(app.NodeTree_Processing);
            app.AfterprocessingNode.Text = 'After processing:';
            app.OpendirectoryNode = uitreenode(app.AfterprocessingNode);
            app.OpendirectoryNode.Text = 'Open directory';
            app.OpeninViewerNode = uitreenode(app.AfterprocessingNode);
            app.OpeninViewerNode.Text = 'Open in Viewer';
            app.ShutdownNode_2 = uitreenode(app.AfterprocessingNode);
            app.ShutdownNode_2.Text = 'Shutdown';
            % Assign Checked Nodes
            app.NodeTree_Processing.CheckedNodes = [app.AutomaticNode, app.OpeninViewerNode];
            % Assign Checked Nodes
            app.NodeTree_Processing.CheckedNodesChangedFcn = createCallbackFcn(app, @NodeTree_ProcessingChanged, true);
            app.BusyLampLabel = uilabel(app.UIFigure_MeMRI_PTB);
            app.BusyLampLabel.Text = 'Busy';
            app.Edit_File = uieditfield(app.UIFigure_MeMRI_PTB, 'text');
            app.Edit_File.ValueChangedFcn = createCallbackFcn(app, @Edit_FileValueChanged, true);
            app.Edit_File.Tooltip = {'Enter the full filepath and press any key to load a file (or use the menu).'};
            app.Button_File_Reload = uibutton(app.UIFigure_MeMRI_PTB, 'push');
            app.Button_File_Reload.ButtonPushedFcn = createCallbackFcn(app, @Button_File_ReloadPushed, true);
            app.Button_File_Reload.Tooltip = {'Reset and reload the initial mat-file.'};
            app.Button_File_Reload.Text = '⟳';
            % Show the figure after all components are created
            app.UIFigure_MeMRI_PTB.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = memri_Processing_GUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure_MeMRI_PTB)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure_MeMRI_PTB)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure_MeMRI_PTB)
        end
    end
end